classdef Comm_Sys < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SystemParametersLabel           matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TransmittedDataParametersLabel  matlab.ui.control.Label
        RaisedCosineParametersLabel     matlab.ui.control.Label
        EqualizerParametersLabel        matlab.ui.control.Label
        ConventionalCodeParametersLabel  matlab.ui.control.Label
        QAMOrderEditFieldLabel          matlab.ui.control.Label
        QAMOrderEditField               matlab.ui.control.EditField
        NumberOfTrainingSymbolsEditFieldLabel  matlab.ui.control.Label
        NumberOfTrainingSymbolsEditField  matlab.ui.control.EditField
        NumberOfDataSymbolsEditFieldLabel  matlab.ui.control.Label
        NumberOfDataSymbolsEditField    matlab.ui.control.EditField
        RollOfffactorEditFieldLabel     matlab.ui.control.Label
        RollOfffactorEditField          matlab.ui.control.EditField
        SpanEditFieldLabel              matlab.ui.control.Label
        SpanEditField                   matlab.ui.control.EditField
        SamplePerSymbolEditFieldLabel   matlab.ui.control.Label
        SamplePerSymbolEditField        matlab.ui.control.EditField
        ReferenceTabEditFieldLabel      matlab.ui.control.Label
        ReferenceTabEditField           matlab.ui.control.EditField
        NumberOfForewordsTapsEditFieldLabel  matlab.ui.control.Label
        NumberOfForewordsTapsEditField  matlab.ui.control.EditField
        NumberOfBackwordTapsEditFieldLabel  matlab.ui.control.Label
        NumberOfBackwordTapsEditField   matlab.ui.control.EditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        StepSizeEditField               matlab.ui.control.EditField
        ConstraintLengthEditFieldLabel  matlab.ui.control.Label
        ConstraintLengthEditField       matlab.ui.control.EditField
        CodeGeneratorEditFieldLabel     matlab.ui.control.Label
        CodeGeneratorEditField          matlab.ui.control.EditField
        TLBEditFieldLabel               matlab.ui.control.Label
        TLBEditField                    matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        OKButton                        matlab.ui.control.Button
        ChooseChannelDropDownLabel      matlab.ui.control.Label
        ChooseChannelDropDown           matlab.ui.control.DropDown
        SIMULATEButton                  matlab.ui.control.Button
        BERSimulationLabel              matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        SingleSimulationLabel           matlab.ui.control.Label
        EBNOEditFieldLabel              matlab.ui.control.Label
        EBNOEditField                   matlab.ui.control.EditField
        PlotConstellationBeforeequalizationButton  matlab.ui.control.Button
        PlotConstellationAfterequalizationButton  matlab.ui.control.Button
        SIMULATEButton_2                matlab.ui.control.Button
        PloterrorfortheequalizerButton  matlab.ui.control.Button
        PlotEqualizerweightVectorButton  matlab.ui.control.Button
        EBNOVectorEditFieldLabel        matlab.ui.control.Label
        EBNOVectorEditField             matlab.ui.control.EditField
        BEREditFieldLabel               matlab.ui.control.Label
        BEREditField                    matlab.ui.control.EditField
        BERHardEditFieldLabel           matlab.ui.control.Label
        BERHardEditField                matlab.ui.control.EditField
        BERSoftEditFieldLabel           matlab.ui.control.Label
        BERSoftEditField                matlab.ui.control.EditField
        GainHardEditFieldLabel          matlab.ui.control.Label
        GainHardEditField               matlab.ui.control.EditField
        GainSoftEditFieldLabel          matlab.ui.control.Label
        GainSoftEditField               matlab.ui.control.EditField
        Label_4                         matlab.ui.control.Label
        CommunicationLinkLabel          matlab.ui.control.Label
        EBNOlimitsEditFieldLabel        matlab.ui.control.Label
        EBNOlimitsEditField             matlab.ui.control.EditField
        UploadButton                    matlab.ui.control.Button
        ImagePathEditFieldLabel         matlab.ui.control.Label
        ImagePathEditField              matlab.ui.control.EditField
        PlotQAMVectorButton             matlab.ui.control.Button
        PloterrorVectorButton           matlab.ui.control.Button
        NumTransmittsEditFieldLabel     matlab.ui.control.Label
        NumTransmittsEditField          matlab.ui.control.EditField
        TransmittTimeEditFieldLabel     matlab.ui.control.Label
        TransmittTimeEditField          matlab.ui.control.EditField
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        SENDButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        M = 40; % Description
        N_train = 100;
        N_sym = 1000;
        rolloff = 0.5;
        span = 6;
        Fs = 16;
        refTap = 11;
        nFwdTaps = 21;
        nFdbkTaps = 21;
        stepsz = 0.01;
        ConstraintLength = 3;
        CodeGenerator = [7 5];
        tlb = 32;
        EBNO = 10;
        EBNO_Vect = 1:10;
        chan1 = 1./[11:-1:7 1 7:11];
        chan2 = [1 4 5 4 12 100 0 21 -1 2 11];
        chan3 = [19 90 25];
        chan4 = [1 0.5 1 4 2 21 1 3 1 -1 2];
        chan5 = [1 2 17 -8 4];
        chan6 = [40 1 44 1 130 50 5 6 18];
        chan7 = [4 1 4 100 1 50 5 6 18];
        chan8 = [90 80 235 30 20];
        chan9 = [100 3 200 66 7];
        chan10 = [8 9 73 9 77 9 298 0 19 02 88 19 21];
        chan11 = [8 9 73 9 77 9 270 0 19 02 88 19 21];
        chan12 = [8 9 44 9 50 9 150 0 19 02 40 19 21];
        chan13 = [4 8 9 18 -88 91 8 9 0 30 700 18 95 81 91 5 39 85 18 82 21];
        chan14 = [90 48 29 8 9 396 12 84 99 12 24];
        chan15 = [19 8 93 300 92 9 8];
        chan16 = [28 34 92 75 92 84 19 570 19 84 17 18 57 38 88];
        chan17 = [28 34 92 95 92 0 19 574 19 4 17 18 57 38 88];
        chan18 = [28 9 66 0 92 0 19 495 99 99 99 18 57 38 88];
        chan19 = [1 4 5 2 51 6 8 9 5];
        chan20 = [1 4 5 2 21 6 70 9 5 6 7 7 3];
        chan21 = [1];
        data_recieved = [];
        data_equalized = [];
        error_vector = [];
        weights_vector = [];
        ratio_before_conv = 0;
        ratio_after_conv_hard = 0;
        ratio_after_conv_soft = 0;
        gain_hard = 0;
        gain_soft = 0;
        i = 1;
        
        trans_sig1 = [];
        QAM_V = [];
        err_Vect = [];
        
    end
    
    
    methods (Access = public)
        
        function [M,N_train,N_sym,rolloff,span,...
           Fs,refTap,nFwdTaps,nFdbkTaps,stepsz,ConstraintLength,...
           CodeGenerator,tlb,EBNO,EBNO_Vect,channels]= func(app)
       
            M = str2num(app.QAMOrderEditField.Value);
            N_train = str2num(app.NumberOfTrainingSymbolsEditField.Value);
            N_sym = str2num(app.NumberOfDataSymbolsEditField.Value);
            rolloff = str2num(app.RollOfffactorEditField.Value);
            span = str2num(app.SpanEditField.Value);
            Fs = str2num(app.SamplePerSymbolEditField.Value);
            refTap = str2num(app.ReferenceTabEditField.Value);
            nFwdTaps = str2num(app.NumberOfForewordsTapsEditField.Value);
            nFdbkTaps = str2num(app.NumberOfBackwordTapsEditField.Value);
            stepsz = str2num(app.StepSizeEditField.Value);
            ConstraintLength = str2num(app.ConstraintLengthEditField.Value);
            CodeGenerator = str2num(str2mat(app.CodeGeneratorEditField.Value));
            tlb = str2num(app.TLBEditField.Value);
            EBNO = str2num(app.EBNOEditField.Value);
            EBNO_Vect = str2num(str2mat(app.EBNOVectorEditField.Value));
            channels = {app.chan1,app.chan2,app.chan3,app.chan4,app.chan5,app.chan6,app.chan7,app.chan8,...
            app.chan9,app.chan10,app.chan11,app.chan12,app.chan13,app.chan14,app.chan15,app.chan16,app.chan17,...
            app.chan18,app.chan19,app.chan20,app.chan21};
        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % PlotConstellationAfterequalizationButton
        function PlotConstellationAfterequalizationButtonPushed(app, event)
            %app.M = str2num(app.QAMOrderEditField.Value);
            const = qammod(0:app.M-1,app.M,'UnitAveragePower',true);
            scatterplot(app.data_equalized);grid on;hold on;scatter(real(const),imag(const),'+','r');...
                title('After Equalization');

        end

        % Button pushed function: 
        % PlotConstellationBeforeequalizationButton
        function PlotConstellationBeforeequalizationButtonPushed(app, event)
            const = qammod(0:app.M-1,app.M,'UnitAveragePower',true);
            scatterplot(app.data_recieved);grid on;hold on;scatter(real(const),imag(const),'+','r');...
                title('Before Equalization')
        end

        % Button pushed function: SIMULATEButton_2
        function SIMULATEButton_2Pushed(app, event)
            [app.M,app.N_train,app.N_sym,app.rolloff,app.span,...
            app.Fs,app.refTap,app.nFwdTaps,app.nFdbkTaps,app.stepsz,app.ConstraintLength,...
            app.CodeGenerator,app.tlb,app.EBNO,app.EBNO_Vect,channels]= func(app);
            k = log2(app.M);
            N = app.N_train+app.N_sym;
            sampling = [1 zeros(1,app.Fs-1)];
            trellis = poly2trellis(app.ConstraintLength,app.CodeGenerator);
        
            data_Train = randsrc(1,app.N_train*log2(app.M),[0 1]);
            data_Sym = randsrc(1,app.N_sym*log2(app.M),[0 1]);
            conv_data = convenc(data_Sym,trellis);
            data = [data_Train conv_data]';

            QAM_data = qammod(data,app.M,'InputType','bit','UnitAveragePower',true);
            decimal_data = qamdemod(QAM_data,app.M,'OutputType','integer','UnitAveragePower',true);
            
            eq = comm.DecisionFeedbackEqualizer('Algorithm','LMS', ...
    'NumForwardTaps',app.nFwdTaps,'NumFeedbackTaps',app.nFdbkTaps,'ReferenceTap',app.refTap, ...
    'StepSize',app.stepsz,'Constellation',qammod(0:app.M-1,app.M,'UnitAveragePower',true));
            SNR = app.EBNO + 3;
            chan = channels{app.i};
            chan = chan./(sqrt(sum(chan.^2)));
            rrcFilter = rcosdesign(app.rolloff, app.span, app.Fs,'sqrt');
            C_1= chan;
            C_1_sam=kron(chan',sampling');
            Len = (length(C_1)-1)/2;
            QAM_data_sampled = kron(QAM_data,sampling');
            QAM_data_sampled_RRq = conv(QAM_data_sampled,rrcFilter);
            QAM_data_sampled_RRq_chan = conv(QAM_data_sampled_RRq,C_1_sam);
            QAM_data_sampled_RRq_chan = awgn(QAM_data_sampled_RRq_chan,SNR,'measured');
            QAM_data_sampled_DRq = conv(QAM_data_sampled_RRq_chan,rrcFilter);
            vect = app.Fs*(app.span+Len)+1:app.Fs:length(QAM_data_sampled_DRq)-app.Fs*(app.span);
            rx = QAM_data_sampled_DRq(vect);
            [data_eq,err,w] = eq(rx,QAM_data(1:app.N_train));
            app.data_recieved = rx;
            app.error_vector = err;
            app.weights_vector=w;
            app.data_equalized = data_eq;
            
            snrdB = SNR + 10*log10(k*0.5);
            noiseVar = 10.^(-snrdB/10);
            % Check Data
            k = log2(app.M);
            l_vec = app.N_train+1+(app.refTap-1):numel(QAM_data);
            bb = data_eq(l_vec);
            recive_signal = qamdemod(bb,app.M,'OutputType','integer','UnitAveragePower',true);

            recive_signal_soft = qamdemod(bb,app.M,'OutputType','approxllr', ...
            'UnitAveragePower',true,'NoiseVariance',noiseVar);
        
            dataSoft = vitdec(recive_signal_soft,trellis,app.tlb,'trunc','unquant');

            v = decimal_data(app.N_train+1:end-(app.refTap-1));

            recive_signal = recive_signal';
            [~,ratio] = biterr(recive_signal, v', k);
            
            app.ratio_before_conv = ratio;
            
            recive_signal_bits = qamdemod(bb,app.M,'OutputType','bit','UnitAveragePower',true);
            deconv_data = vitdec(recive_signal_bits,trellis,app.tlb,'trunc','hard');
            dd = data_Sym(1:numel(deconv_data));
            [~,ratio_conv] = symerr(deconv_data',dd);
            app.ratio_after_conv_hard = ratio_conv;
            [~,ratio_s]  = symerr(dd, dataSoft');
            app.ratio_after_conv_soft = ratio_s;
            app.gain_hard = 10*log10(ratio/ratio_conv);
            app.gain_soft = 10*log10(ratio/ratio_s);
            app.BEREditField.Value = num2str(ratio);
            app.BERHardEditField.Value = num2str(ratio_conv);
            app.BERSoftEditField.Value = num2str(ratio_s);
            app.GainHardEditField.Value = num2str(app.gain_hard);
            app.GainSoftEditField.Value = num2str(app.gain_soft);
            msgbox('Simulation Finished');
        end

        % Button pushed function: PloterrorfortheequalizerButton
        function PloterrorfortheequalizerButtonPushed(app, event)
            plot(abs(app.error_vector));title('Error vector For Equalizer Output');...
                xlabel('Symbols'); ylabel('|e|');
        end

        % Button pushed function: PlotEqualizerweightVectorButton
        function PlotEqualizerweightVectorButtonPushed(app, event)
            subplot(3,1,1); stem(real(app.weights_vector)); ylabel('real(weights)'); xlabel('Tap'); grid on;
            line([app.nFwdTaps+0.5 app.nFdbkTaps+0.5], [-max(real(app.weights_vector)) max(real(app.weights_vector))], 'Color', 'r', 'LineWidth', 1)
            title('Equalizer Tap Weights')
            subplot(3,1,2); stem(imag(app.weights_vector)); ylabel('imag(weights)'); xlabel('Tap'); grid on; 
            line([app.nFwdTaps+0.5 app.nFdbkTaps+0.5], [-max(real(app.weights_vector)) max(real(app.weights_vector))], 'Color', 'r', 'LineWidth', 1)
            subplot(3,1,3); stem(abs(app.weights_vector)); ylabel('abs(weights)'); xlabel('Tap'); grid on; 
            line([app.nFwdTaps+0.5 app.nFdbkTaps+0.5], [-max(real(app.weights_vector)) max(real(app.weights_vector))], 'Color', 'r', 'LineWidth', 1)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            channels = {app.chan1,app.chan2,app.chan3,app.chan4,app.chan5,app.chan6,app.chan7,app.chan8,...
            app.chan9,app.chan10,app.chan11,app.chan12,app.chan13,app.chan14,app.chan15,app.chan16,app.chan17,...
            app.chan18,app.chan19,app.chan20,app.chan21};
        C = {'1','2','3','4','5','6','7','8','9','10',...
            '11','12','13','14','15','16','17','18','19',...
            '20','21'};
        for j=1:21
            if(str2num(app.ChooseChannelDropDown.Value) == j)
                app.i = j;
                break;
            end 
        end
        chan = channels{app.i};
        stem(app.UIAxes,chan/sqrt(sum(chan.^2)),'-r');grid(app.UIAxes,'on');
        end

        % Button pushed function: SIMULATEButton
        function SIMULATEButtonPushed(app, event)
            [app.M,app.N_train,app.N_sym,app.rolloff,app.span,...
            app.Fs,app.refTap,app.nFwdTaps,app.nFdbkTaps,app.stepsz,app.ConstraintLength,...
            app.CodeGenerator,app.tlb,app.EBNO,app.EBNO_Vect,channels]= func(app);
            k = log2(app.M);
            N = app.N_train+app.N_sym;
            sampling = [1 zeros(1,app.Fs-1)];
            trellis = poly2trellis(app.ConstraintLength,app.CodeGenerator);
            ber = [];
            ber_hard = [];
            ber_soft = [];
            EbNo = app.EBNO_Vect;
            
            for j = 1:length(EbNo)
                toterr = 0;
                Num_Transmitts = 0;
                
                %Code
                %Transmitt
                data_Train = randsrc(1,app.N_train*log2(app.M),[0 1]);
                data_Sym = randsrc(1,app.N_sym*log2(app.M),[0 1]);
                conv_data = convenc(data_Sym,trellis);
                data = [data_Train conv_data]';

                QAM_data = qammod(data,app.M,'InputType','bit','UnitAveragePower',true);
                decimal_data = qamdemod(QAM_data,app.M,'OutputType','integer','UnitAveragePower',true);
            
                eq = comm.DecisionFeedbackEqualizer('Algorithm','LMS', ...
                    'NumForwardTaps',app.nFwdTaps,'NumFeedbackTaps',app.nFdbkTaps,'ReferenceTap',app.refTap, ...
                    'StepSize',app.stepsz,'Constellation',qammod(0:app.M-1,app.M,'UnitAveragePower',true));
                SNR = EbNo(j) + 3;
                chan = channels{randsrc(1,1,1:20)};
                chan = chan./(sqrt(sum(chan.^2)));
                rrcFilter = rcosdesign(app.rolloff, app.span, app.Fs,'sqrt');
                C_1= chan;
                C_1_sam=kron(chan',sampling');
                Len = (length(C_1)-1)/2;
                QAM_data_sampled = kron(QAM_data,sampling');
                QAM_data_sampled_RRq = conv(QAM_data_sampled,rrcFilter);
                QAM_data_sampled_RRq_chan = conv(QAM_data_sampled_RRq,C_1_sam);
                QAM_data_sampled_RRq_chan = awgn(QAM_data_sampled_RRq_chan,SNR,'measured');
                QAM_data_sampled_DRq = conv(QAM_data_sampled_RRq_chan,rrcFilter);
                vect = app.Fs*(app.span+Len)+1:app.Fs:length(QAM_data_sampled_DRq)-app.Fs*(app.span);
                rx = QAM_data_sampled_DRq(vect);
                [data_eq,err,w] = eq(rx,QAM_data(1:app.N_train));
                app.data_recieved = rx;
                app.error_vector = err;
                app.weights_vector=w;
                app.data_equalized = data_eq;
                
                snrdB = SNR + 10*log10(k*0.5);
                noiseVar = 10.^(-snrdB/10);
                % Check Data
                k = log2(app.M);
                l_vec = app.N_train+1+(app.refTap-1):numel(QAM_data);
                bb = data_eq(l_vec);
                recive_signal = qamdemod(bb,app.M,'OutputType','integer','UnitAveragePower',true);
    
                recive_signal_soft = qamdemod(bb,app.M,'OutputType','approxllr', ...
                'UnitAveragePower',true,'NoiseVariance',noiseVar);
            
                dataSoft = vitdec(recive_signal_soft,trellis,app.tlb,'trunc','unquant');
    
                v = decimal_data(app.N_train+1:end-(app.refTap-1));
    
                recive_signal = recive_signal';
                [N_err,ratio] = biterr(recive_signal, v', k);
                
                app.ratio_before_conv = ratio;
                
                recive_signal_bits = qamdemod(bb,app.M,'OutputType','bit','UnitAveragePower',true);
                deconv_data = vitdec(recive_signal_bits,trellis,app.tlb,'trunc','hard');
                dd = data_Sym(1:numel(deconv_data));
                [N_err_hard,ratio_conv] = symerr(deconv_data',dd);
                app.ratio_after_conv_hard = ratio_conv;
                [N_err_soft,ratio_s]  = symerr(dd, dataSoft');
                ber = [ber ratio];
                ber_hard = [ber_hard ratio_conv];
                ber_soft = [ber_soft ratio_s];
            end
            semilogy(app.UIAxes2,EbNo,ber,EbNo,ber_hard,EbNo,ber_soft);...
                grid(app.UIAxes2,'on');xlabel(app.UIAxes2,'EbNo');...
                ylabel(app.UIAxes2,'BER');title(app.UIAxes2,'BER Communication System');...
                legend(app.UIAxes2,'Uncoded','Hard','Soft','location','best');
            
            msgbox('Simulation Finished');
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            image_name = app.ImagePathEditField.Value;
            image = imread(image_name);
            image = rgb2gray(image);
            im_Vect = reshape(image',1,numel(image));
            bin_im = dec2bin(im_Vect);
            bin_im1 = reshape(bin_im',1,numel(bin_im));
            bin_im2 = str2num(bin_im1')';
            %%
            Z = ceil(numel(bin_im2)/168000);
            trans_sig = zeros(Z,168000);
            for j = 1:Z
                if(j*168000 <= numel(bin_im2))
                    trans_sig(j,:) = bin_im2((j-1)*168000+1:j*168000);
                else
                    trans_sig(j,:) = [bin_im2((j-1)*168000+1:end) zeros(1,168000 - length(bin_im2((j-1)*168000+1:end)))];
                end
            end
            app.trans_sig1 = [trans_sig zeros(Z,1680)];

            imshow(image, 'parent', app.UIAxes3);

        end

        % Button pushed function: SENDButton
        function SENDButtonPushed(app, event)
            [app.M,app.N_train,app.N_sym,app.rolloff,app.span,...
            app.Fs,app.refTap,app.nFwdTaps,app.nFdbkTaps,app.stepsz,app.ConstraintLength,...
            app.CodeGenerator,app.tlb,app.EBNO,app.EBNO_Vect,channels]= func(app);
            
            image_name = app.ImagePathEditField.Value;
            image = imread(image_name);
            image = rgb2gray(image);
            im_Vect = reshape(image',1,numel(image));
            bin_im = dec2bin(im_Vect);
            bin_im1 = reshape(bin_im',1,numel(bin_im));
            bin_im2 = str2num(bin_im1')';
            
            
            packet_len = 168000;
            N_packets = size(app.trans_sig1,1);
            QAM_Ord = 64;
            sampling = [1 zeros(1,app.Fs-1)];
            rrcFilter = rcosdesign(app.rolloff, app.span, app.Fs,'sqrt');
            
            % Conv-Code
            trellis = poly2trellis(app.ConstraintLength,app.CodeGenerator);
    
            SNR_Vect = str2num(str2mat(app.EBNOlimitsEditField.Value));
            data_res = [];
            rr = [];
            Q_ord_vect = [];
            j = 1;
            % Conv-Code
            tic;
            while( j <= N_packets)
                SNR = randsrc(1,1,SNR_Vect);
                chan = channels{randsrc(1,1,1:20)};
                chan = chan./(sqrt(sum(chan.^2))); 
                app.N_sym = packet_len/log2(QAM_Ord);
                app.N_train = floor(app.N_sym/10);
                N = app.N_train+app.N_sym;


                app.M = QAM_Ord;
                k = log2(app.M);
                eq = comm.DecisionFeedbackEqualizer('Algorithm','LMS', ...
                'NumForwardTaps',app.nFwdTaps,'NumFeedbackTaps',app.nFdbkTaps,'ReferenceTap',app.refTap, ...
                'StepSize',app.stepsz,'Constellation',qammod(0:app.M-1,app.M,'UnitAveragePower',true));
            
                data_Train = randsrc(1,app.N_train*log2(app.M),[0 1]);
                data_Sym = app.trans_sig1(j,:);
                conv_data = convenc(data_Sym,trellis);
                data = [data_Train conv_data]';
            
                QAM_data = qammod(data,app.M,'InputType','bit','UnitAveragePower',true);
                C_1= chan;
                C_1_sam=kron(chan',sampling');
                Len = (length(C_1)-1)/2;
                QAM_data_sampled = kron(QAM_data,sampling');
                QAM_data_sampled_RRq = conv(QAM_data_sampled,rrcFilter);
                QAM_data_sampled_RRq_chan = conv(QAM_data_sampled_RRq,C_1_sam);
                QAM_data_sampled_RRq_chan = awgn(QAM_data_sampled_RRq_chan,SNR + 3,'measured');
                QAM_data_sampled_DRq = conv(QAM_data_sampled_RRq_chan,rrcFilter);
                vect = app.Fs*(app.span+Len)+1:app.Fs:length(QAM_data_sampled_DRq)-app.Fs*(app.span);
                rx = QAM_data_sampled_DRq(vect);
                [data_eq,err,w] = eq(rx,QAM_data(1:app.N_train));
                
                snrdB = SNR + 10*log10(k*0.5);
                noiseVar = 10.^(-snrdB/10);
                % Check Data
                l_vec = app.N_train+1+(app.refTap-1):numel(QAM_data);
                bb = data_eq(l_vec);
                recive_signal_soft = qamdemod(bb,app.M,'OutputType','approxllr', ...
                            'UnitAveragePower',true,'NoiseVariance',noiseVar);
                dataSoft = vitdec(recive_signal_soft,trellis,app.tlb,'trunc','unquant');
                dd = data_Sym(1:numel(dataSoft));
                [mm_s,ratio_s]  = symerr(dd, dataSoft');
                rr = [rr ratio_s];
                if(mm_s ~= 0)
                    if (QAM_Ord ~= 4)
                        QAM_Ord = QAM_Ord/2;
                    else
                        QAM_Ord = 4;
                    end
                    j = j-1;
                else
                    Q_ord_vect = [Q_ord_vect QAM_Ord];
                    data_res = [data_res dataSoft(1:168000)'];
                    QAM_Ord = 64;
                end
                j = j+1;
            end
            elapsedTime = toc;
            
            bin_res1 = dec2bin(data_res(1:length(bin_im2)))';
            bin_res2 = reshape(bin_res1,size(bin_im,2),size(bin_im,1))';
            bin_res3 = bin2dec(bin_res2);
            bin_res4 = reshape(bin_res3,size(image,2),size(image,1))';
            res_im = uint8(bin_res4);
            
            app.QAM_V = Q_ord_vect;
            app.err_Vect = rr;
            app.TransmittTimeEditField.Value = num2str(elapsedTime);
            app.NumTransmittsEditField.Value = num2str(numel(rr));
            imshow(res_im, 'parent', app.UIAxes4);
        end

        % Button pushed function: PlotQAMVectorButton
        function PlotQAMVectorButtonPushed(app, event)
            stem(app.QAM_V);title('QAM Order in Successful Transmittions');...
                grid on;
        end

        % Button pushed function: PloterrorVectorButton
        function PloterrorVectorButtonPushed(app, event)
            plot(app.err_Vect);title('Error in all transmittions');...
                grid on;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SystemParametersLabel = uilabel(app.UIFigure);
            app.SystemParametersLabel.Text = 'System Parameters';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '---------------------------';
            app.TransmittedDataParametersLabel = uilabel(app.UIFigure);
            app.TransmittedDataParametersLabel.Text = 'Transmitted Data Parameters';
            app.RaisedCosineParametersLabel = uilabel(app.UIFigure);
            app.RaisedCosineParametersLabel.Text = 'Raised Cosine Parameters';
            app.EqualizerParametersLabel = uilabel(app.UIFigure);
            app.EqualizerParametersLabel.Text = 'Equalizer Parameters';
            app.ConventionalCodeParametersLabel = uilabel(app.UIFigure);
            app.ConventionalCodeParametersLabel.Text = 'Conventional Code Parameters';
            app.QAMOrderEditFieldLabel = uilabel(app.UIFigure);
            app.QAMOrderEditFieldLabel.Text = {'QAM'; 'Order'};
            app.QAMOrderEditField = uieditfield(app.UIFigure, 'text');
            app.QAMOrderEditField.Value = '4';
            app.NumberOfTrainingSymbolsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberOfTrainingSymbolsEditFieldLabel.Text = {'Number Of'; 'Training Symbols'};
            app.NumberOfTrainingSymbolsEditField = uieditfield(app.UIFigure, 'text');
            app.NumberOfTrainingSymbolsEditField.Value = '500';
            app.NumberOfDataSymbolsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberOfDataSymbolsEditFieldLabel.Text = {'Number Of '; 'Data Symbols'};
            app.NumberOfDataSymbolsEditField = uieditfield(app.UIFigure, 'text');
            app.NumberOfDataSymbolsEditField.Value = '1000';
            app.RollOfffactorEditFieldLabel = uilabel(app.UIFigure);
            app.RollOfffactorEditFieldLabel.Text = 'RollOff factor';
            app.RollOfffactorEditField = uieditfield(app.UIFigure, 'text');
            app.RollOfffactorEditField.Value = '0.5';
            app.SpanEditFieldLabel = uilabel(app.UIFigure);
            app.SpanEditFieldLabel.Text = 'Span';
            app.SpanEditField = uieditfield(app.UIFigure, 'text');
            app.SpanEditField.Value = '6';
            app.SamplePerSymbolEditFieldLabel = uilabel(app.UIFigure);
            app.SamplePerSymbolEditFieldLabel.Text = 'Sample Per Symbol';
            app.SamplePerSymbolEditField = uieditfield(app.UIFigure, 'text');
            app.SamplePerSymbolEditField.Value = '16';
            app.ReferenceTabEditFieldLabel = uilabel(app.UIFigure);
            app.ReferenceTabEditFieldLabel.Text = 'Reference Tab';
            app.ReferenceTabEditField = uieditfield(app.UIFigure, 'text');
            app.ReferenceTabEditField.Value = '11';
            app.NumberOfForewordsTapsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberOfForewordsTapsEditFieldLabel.Text = {'Number Of'; 'Forewords Taps'};
            app.NumberOfForewordsTapsEditField = uieditfield(app.UIFigure, 'text');
            app.NumberOfForewordsTapsEditField.Value = '21';
            app.NumberOfBackwordTapsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberOfBackwordTapsEditFieldLabel.Text = {'Number Of'; 'Backword Taps'};
            app.NumberOfBackwordTapsEditField = uieditfield(app.UIFigure, 'text');
            app.NumberOfBackwordTapsEditField.Value = '21';
            app.StepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.UIFigure, 'text');
            app.StepSizeEditField.Value = '0.01';
            app.ConstraintLengthEditFieldLabel = uilabel(app.UIFigure);
            app.ConstraintLengthEditFieldLabel.Text = 'Constraint Length';
            app.ConstraintLengthEditField = uieditfield(app.UIFigure, 'text');
            app.ConstraintLengthEditField.Value = '3';
            app.CodeGeneratorEditFieldLabel = uilabel(app.UIFigure);
            app.CodeGeneratorEditFieldLabel.Text = 'Code Generator';
            app.CodeGeneratorEditField = uieditfield(app.UIFigure, 'text');
            app.CodeGeneratorEditField.Value = '[7 5]';
            app.TLBEditFieldLabel = uilabel(app.UIFigure);
            app.TLBEditFieldLabel.Text = 'TLB';
            app.TLBEditField = uieditfield(app.UIFigure, 'text');
            app.TLBEditField.Value = '2';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Channel Coefficients')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'BER Performance')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ChooseChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChooseChannelDropDownLabel.Text = 'Choose Channel';
            app.ChooseChannelDropDown = uidropdown(app.UIFigure);
            app.ChooseChannelDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21'};
            app.ChooseChannelDropDown.Value = '1';
            app.SIMULATEButton = uibutton(app.UIFigure, 'push');
            app.SIMULATEButton.ButtonPushedFcn = createCallbackFcn(app, @SIMULATEButtonPushed, true);
            app.SIMULATEButton.Text = 'SIMULATE';
            app.BERSimulationLabel = uilabel(app.UIFigure);
            app.BERSimulationLabel.Text = 'BER Simulation';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.SingleSimulationLabel = uilabel(app.UIFigure);
            app.SingleSimulationLabel.Text = 'Single Simulation';
            app.EBNOEditFieldLabel = uilabel(app.UIFigure);
            app.EBNOEditFieldLabel.Text = 'EBNO';
            app.EBNOEditField = uieditfield(app.UIFigure, 'text');
            app.EBNOEditField.Value = '4';
            app.PlotConstellationBeforeequalizationButton = uibutton(app.UIFigure, 'push');
            app.PlotConstellationBeforeequalizationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotConstellationBeforeequalizationButtonPushed, true);
            app.PlotConstellationBeforeequalizationButton.Text = {'Plot Constellation'; 'Before equalization'};
            app.PlotConstellationAfterequalizationButton = uibutton(app.UIFigure, 'push');
            app.PlotConstellationAfterequalizationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotConstellationAfterequalizationButtonPushed, true);
            app.PlotConstellationAfterequalizationButton.Text = {'Plot Constellation'; 'After equalization'};
            app.SIMULATEButton_2 = uibutton(app.UIFigure, 'push');
            app.SIMULATEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SIMULATEButton_2Pushed, true);
            app.SIMULATEButton_2.Text = 'SIMULATE';
            app.PloterrorfortheequalizerButton = uibutton(app.UIFigure, 'push');
            app.PloterrorfortheequalizerButton.ButtonPushedFcn = createCallbackFcn(app, @PloterrorfortheequalizerButtonPushed, true);
            app.PloterrorfortheequalizerButton.Text = {'Plot error for '; 'the equalizer'};
            app.PlotEqualizerweightVectorButton = uibutton(app.UIFigure, 'push');
            app.PlotEqualizerweightVectorButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEqualizerweightVectorButtonPushed, true);
            app.PlotEqualizerweightVectorButton.Text = {'Plot Equalizer'; 'weight Vector'};
            app.EBNOVectorEditFieldLabel = uilabel(app.UIFigure);
            app.EBNOVectorEditFieldLabel.Text = 'EBNO Vector';
            app.EBNOVectorEditField = uieditfield(app.UIFigure, 'text');
            app.EBNOVectorEditField.Value = '16';
            app.BEREditFieldLabel = uilabel(app.UIFigure);
            app.BEREditFieldLabel.Text = 'BER';
            app.BEREditField = uieditfield(app.UIFigure, 'text');
            app.BERHardEditFieldLabel = uilabel(app.UIFigure);
            app.BERHardEditFieldLabel.Text = 'BER Hard';
            app.BERHardEditField = uieditfield(app.UIFigure, 'text');
            app.BERSoftEditFieldLabel = uilabel(app.UIFigure);
            app.BERSoftEditFieldLabel.Text = 'BER Soft';
            app.BERSoftEditField = uieditfield(app.UIFigure, 'text');
            app.GainHardEditFieldLabel = uilabel(app.UIFigure);
            app.GainHardEditFieldLabel.Text = 'Gain Hard';
            app.GainHardEditField = uieditfield(app.UIFigure, 'text');
            app.GainSoftEditFieldLabel = uilabel(app.UIFigure);
            app.GainSoftEditFieldLabel.Text = 'Gain Soft';
            app.GainSoftEditField = uieditfield(app.UIFigure, 'text');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '-----------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.CommunicationLinkLabel = uilabel(app.UIFigure);
            app.CommunicationLinkLabel.Text = 'Communication Link';
            app.EBNOlimitsEditFieldLabel = uilabel(app.UIFigure);
            app.EBNOlimitsEditFieldLabel.Text = 'EBNO limits';
            app.EBNOlimitsEditField = uieditfield(app.UIFigure, 'text');
            app.EBNOlimitsEditField.Value = '16';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.ImagePathEditFieldLabel = uilabel(app.UIFigure);
            app.ImagePathEditFieldLabel.Text = 'Image Path';
            app.ImagePathEditField = uieditfield(app.UIFigure, 'text');
            app.PlotQAMVectorButton = uibutton(app.UIFigure, 'push');
            app.PlotQAMVectorButton.ButtonPushedFcn = createCallbackFcn(app, @PlotQAMVectorButtonPushed, true);
            app.PlotQAMVectorButton.Text = 'Plot QAM Vector';
            app.PloterrorVectorButton = uibutton(app.UIFigure, 'push');
            app.PloterrorVectorButton.ButtonPushedFcn = createCallbackFcn(app, @PloterrorVectorButtonPushed, true);
            app.PloterrorVectorButton.Text = 'Plot error Vector';
            app.NumTransmittsEditFieldLabel = uilabel(app.UIFigure);
            app.NumTransmittsEditFieldLabel.Text = 'Num Transmitts';
            app.NumTransmittsEditField = uieditfield(app.UIFigure, 'text');
            app.TransmittTimeEditFieldLabel = uilabel(app.UIFigure);
            app.TransmittTimeEditFieldLabel.Text = 'Transmitt Time';
            app.TransmittTimeEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Image to transfer')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Recieved Image')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.SENDButton = uibutton(app.UIFigure, 'push');
            app.SENDButton.ButtonPushedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.SENDButton.Text = 'SEND';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Comm_Sys

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end