classdef MFandWF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        loadParametersButton         matlab.ui.control.Button
        choosewaveFormTypeDropDownLabel  matlab.ui.control.Label
        choosewaveFormTypeDropDown   matlab.ui.control.DropDown
        createwaveFormandmatchedfilterButton  matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        plotwaveformButton           matlab.ui.control.Button
        plotAmbiguityfunctionButton  matlab.ui.control.Button
        chooseambiguityfunctionDropDownLabel  matlab.ui.control.Label
        chooseambiguityfunctionDropDown  matlab.ui.control.DropDown
        cutValueEditFieldLabel       matlab.ui.control.Label
        cutValueEditField            matlab.ui.control.EditField
    end

    
    properties (Access = public)
        % Radar Parameters
        % Radar charactaristic parameters
        pd = 0.9;                              % Probability of detection
        pfa = 1e-6;                            % Probability of false alarm
        max_range = 10000;                     % Maximum unambiguous range
        range_res = 100;                        % Required range resolution
        tgt_rcs = 10;                          % Required target radar cross section
        PassLossMarin = 1000;                   % Power Marin On Pass Loss
        num_pulse_int = 10;                     % Number of integrated pulses
        fc = 10e9;                              % Operating frequency
        tx_gain = 35;
        prop_speed = physconst('LightSpeed');   % Propagation speed;
        pulse_width = 10e-6;
        pulse_bw = 1e5;
        prf = 15000;
        peak_power = 2000;
        fs = 2e5;
        waveLength = 3e8/(1039);
        fast_time_grid = [];
        slow_time_grid = [];
        waveform = [];
        tvg = [];
        %Matched Filter
        matchingcoeff =[];
        matchedfilter = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadParametersButton
        function loadParametersButtonPushed(app, event)
            load('initialParameters.mat',"var");
            app.pd = var(1);
            app.pfa = var(2);
            app.range_res = var(3);
            app.tgt_rcs = var(4);
            app.PassLossMarin = var(5);
            app.num_pulse_int = var(6);
            app.fc = var(7);
            app.tx_gain = var(8);
            app.prop_speed = var(9);
            app.pulse_width = var(10);
            app.pulse_bw = var(11);
            app.prf = var(12);
            app.fs = var(13);
            app.peak_power = var(14);
            app.fast_time_grid = unigrid(0,1/app.fs,1/max(app.prf),'[)');
            app.slow_time_grid = (0:app.num_pulse_int-1)/max(app.prf);
        end

        % Button pushed function: 
        % createwaveFormandmatchedfilterButton
        function createwaveFormandmatchedfilterButtonPushed(app, event)
            type = app.choosewaveFormTypeDropDown.Value;
            range_gates = app.prop_speed*app.fast_time_grid/2;
            if(type == "Rectangular")
                app.waveform = phased.RectangularWaveform(...
                    'PulseWidth',app.pulse_width,...
                    'PRF',app.prf,...
                    'SampleRate',app.fs);
            elseif(type == "LMF")
                app.waveform = phased.LinearFMWaveform(...
                    'SweepBandwidth',app.pulse_bw,...
                    'PulseWidth',app.pulse_width,...
                    'PRF',app.prf,...
                    'SampleRate',app.fs);
            elseif(type == "Barker")
                app.waveform = phased.PhaseCodedWaveform('Code','Barker',...
                    'ChipWidth',app.pulse_width/app.pulse_bw,'NumChips',app.pulse_bw,'PRF',app.prf,...
                    'SampleRate',app.fs,...
                    'OutputFormat','Pulses','NumPulses',1);
            end
            % Time Variant gain
            lambda = app.prop_speed/app.fc;
            app.tvg = phased.TimeVaryingGain(...
                'RangeLoss',2*fspl(range_gates,lambda),...
                'ReferenceLoss',2*fspl(max(range_gates),lambda));
            
            %Matched Filter
            app.matchingcoeff = getMatchedFilter(app.waveform);
            app.matchedfilter = phased.MatchedFilter('Coefficients',app.matchingcoeff);
            Waveform = app.waveform;
            Matchedfilter = app.matchedfilter;
            Matchingcoeff = app.matchingcoeff;
            Tvg = app.tvg;
            save('WFandMF',"Waveform","Matchingcoeff","Matchedfilter","Tvg");
        end

        % Button pushed function: plotwaveformButton
        function plotwaveformButtonPushed(app, event)
            % Plot WaveForm
            lfmwav = app.waveform();
            nsamp = size(lfmwav,1);
            t = [0:(nsamp-1)]/app.fs;
            plot(app.UIAxes,t*1000,real(lfmwav))
            xlabel(app.UIAxes,'Time (millisec)');
            ylabel(app.UIAxes,'Amplitude');
            title(app.UIAxes,'WaveForm');
            grid(app.UIAxes,'on');

        end

        % Button pushed function: plotAmbiguityfunctionButton
        function plotAmbiguityfunctionButtonPushed(app, event)
            cut_val = str2num(app.cutValueEditField.Value);
            Type = app.chooseambiguityfunctionDropDown.Value;
            delayCut = cut_val(1);
            dopplerCut = cut_val(2);
            wav = app.waveform();
            
            if(Type == "Time")
                [AmbFunTime,delay] = ambgfun(wav,app.waveform.SampleRate,app.waveform.PRF,'Cut','Delay','CutValue',delayCut);
                plot(app.UIAxes2,delay*1e6,AmbFunTime);
                axis(app.UIAxes2,'tight') ; grid(app.UIAxes2,'on');
                xlabel(app.UIAxes2,'Delay \tau (us)');ylabel(app.UIAxes2,'Amplitude');
                title(app.UIAxes2,'Waveform Ambiguity Function');
            elseif(Type == "Doppler")
                [AmbFunDoppler,doppler]= ambgfun(wav,app.waveform.SampleRate,app.waveform.PRF,'Cut','Doppler','CutValue',dopplerCut);
                plot(app.UIAxes2,doppler/1e3,AmbFunDoppler);
                axis(app.UIAxes2,'tight') ; grid(app.UIAxes2,'on');
                xlabel(app.UIAxes2,'Doppler f_d (kHz)');ylabel(app.UIAxes2,'Amplitude');
                title(app.UIAxes2,'Waveform Ambiguity Function');
            elseif(Type == "3D")
                
                [afmag_lfm,delay_lfm,doppler_lfm] = ambgfun(wav,app.waveform.SampleRate,...
                    app.waveform.PRF);
                surf(delay_lfm*1e6,doppler_lfm/1e3,afmag_lfm,'LineStyle','none'); 
                axis tight; grid on; view([140,35]); colorbar;
                xlabel('Delay \tau (us)');ylabel('Doppler f_d (kHz)');
                title('Linear FM Pulse Waveform Ambiguity Function');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadParametersButton = uibutton(app.UIFigure, 'push');
            app.loadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @loadParametersButtonPushed, true);
            app.loadParametersButton.Text = 'load Parameters';
            app.choosewaveFormTypeDropDownLabel = uilabel(app.UIFigure);
            app.choosewaveFormTypeDropDownLabel.Text = 'choose waveForm Type';
            app.choosewaveFormTypeDropDown = uidropdown(app.UIFigure);
            app.choosewaveFormTypeDropDown.Items = {'Rectangular', 'LMF', 'Barker'};
            app.choosewaveFormTypeDropDown.Value = 'Rectangular';
            app.createwaveFormandmatchedfilterButton = uibutton(app.UIFigure, 'push');
            app.createwaveFormandmatchedfilterButton.ButtonPushedFcn = createCallbackFcn(app, @createwaveFormandmatchedfilterButtonPushed, true);
            app.createwaveFormandmatchedfilterButton.Text = {'create waveForm'; 'and matched filter '};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.plotwaveformButton = uibutton(app.UIFigure, 'push');
            app.plotwaveformButton.ButtonPushedFcn = createCallbackFcn(app, @plotwaveformButtonPushed, true);
            app.plotwaveformButton.Text = 'plot waveform';
            app.plotAmbiguityfunctionButton = uibutton(app.UIFigure, 'push');
            app.plotAmbiguityfunctionButton.ButtonPushedFcn = createCallbackFcn(app, @plotAmbiguityfunctionButtonPushed, true);
            app.plotAmbiguityfunctionButton.Text = 'plot Ambiguity function';
            app.chooseambiguityfunctionDropDownLabel = uilabel(app.UIFigure);
            app.chooseambiguityfunctionDropDownLabel.Text = {'choose ambiguity'; 'function'};
            app.chooseambiguityfunctionDropDown = uidropdown(app.UIFigure);
            app.chooseambiguityfunctionDropDown.Items = {'Time', 'Doppler', '3D'};
            app.chooseambiguityfunctionDropDown.Value = 'Time';
            app.cutValueEditFieldLabel = uilabel(app.UIFigure);
            app.cutValueEditFieldLabel.Text = 'cut Value';
            app.cutValueEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MFandWF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end