classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SetParametersButton            matlab.ui.control.Button
        loadButton                     matlab.ui.control.Button
        LoadSimulationObjectButton     matlab.ui.control.Button
        loadAntennaButton              matlab.ui.control.Button
        ViewAntennaPatternButton       matlab.ui.control.Button
        SetTargetRadarPositionParametersButton  matlab.ui.control.Button
        loadButton_2                   matlab.ui.control.Button
        configurewaveformandmatchedfilterButton  matlab.ui.control.Button
        loadButton_3                   matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        ConfigureTrackerButton         matlab.ui.control.Button
        loadtrackerButton              matlab.ui.control.Button
        LoadTrackerPlotterButton       matlab.ui.control.Button
        RadarParametersLabel           matlab.ui.control.Label
        TrackarConfigrationLabel       matlab.ui.control.Label
        TargetsandEnvironmentLabel     matlab.ui.control.Label
        configureclutterandCFARdetectorButton  matlab.ui.control.Button
        loadButton_4                   matlab.ui.control.Button
        WaveFormConfigrationLabel      matlab.ui.control.Label
        LoadSystemObjectsLabel         matlab.ui.control.Label
        beginsimulationButton          matlab.ui.control.Button
        showRDmapButton                matlab.ui.control.Button
        withgroundclutterCheckBox      matlab.ui.control.CheckBox
        withrainclutterCheckBox        matlab.ui.control.CheckBox
        MTDsimulationstateButtonGroup  matlab.ui.container.ButtonGroup
        datagenerationButton           matlab.ui.control.RadioButton
        radarprocessingButton          matlab.ui.control.RadioButton
        Choose_AntennaDropDownLabel    matlab.ui.control.Label
        Choose_AntennaDropDown         matlab.ui.control.DropDown
        NFEditFieldLabel               matlab.ui.control.Label
        NFEditField                    matlab.ui.control.EditField
        ProccessingTypeDropDownLabel   matlab.ui.control.Label
        ProccessingTypeDropDown        matlab.ui.control.DropDown
        anglegridEditFieldLabel        matlab.ui.control.Label
        anglegridEditField             matlab.ui.control.EditField
        numberofrotationsEditFieldLabel  matlab.ui.control.Label
        numberofrotationsEditField     matlab.ui.control.EditField
        radarturntimesEditFieldLabel   matlab.ui.control.Label
        radarturntimesEditField        matlab.ui.control.EditField
        doplermapindexEditFieldLabel   matlab.ui.control.Label
        doplermapindexEditField        matlab.ui.control.EditField
        FFTsizeEditFieldLabel          matlab.ui.control.Label
        FFTsizeEditField               matlab.ui.control.EditField
    end

    
    properties (Access = public)
        % Radar Parameters
        % Radar charactaristic parameters
        pd = 0.9;                              % Probability of detection
        pfa = 1e-6;                            % Probability of false alarm
        max_range = 10000;                     % Maximum unambiguous range
        range_res = 100;                        % Required range resolution
        tgt_rcs = 10;                          % Required target radar cross section
        PassLossMarin = 1000;                   % Power Marin On Pass Loss
        num_pulse_int = 10;                     % Number of integrated pulses
        fc = 10e9;                              % Operating frequency
        tx_gain = 35;
        prop_speed = physconst('LightSpeed');   % Propagation speed; 
        pulse_width = 10e-6;
        pulse_bw = 1e5;
        prf = 15000;
        peak_power = 2000;
        fs = 2e5;
        waveLength = 3e8/(1039);
        fast_time_grid = [];
        slow_time_grid = [];
        waveform = [];
        tvg = [];
        %Matched Filter
        matchingcoeff =[];
        matchedfilter = [];
        sArray = [];
        radiator = [];
        collector =[];
        reciever = [];
        transmitter = [];
        channel = [];
        
        sensormotion = [];
        tgtmotion = [];
        target = [];
        
        tracker = [];
        positionSelector =[];
        velocitySelector = [];
        ratio = 10;
        
        tp = [];
        trackp = [];
        
        clutter = [];
        cfar_detector = [];
        cfar_detector1 = [];
        cfar_detector2 = [];
        N_train = 0;
        N_guard = 0;
        all_range_doppler_maps = [];
        speed_axis = [] ;
        density = 8e-4;
        rngBinsNum = 500;
        rain_speed = 10;
        distortion = 2;
        radar_steering =120;
        rain_steering = 20;
        Noise_level = 30;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            Parameters;
            
        end

        % Callback function
        function ButtonPushed(app, event)
            app.disp_prfEditField.Value = num2str(app.prf);
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            load('initialParameters.mat',"var");
            app.pd = var(1);
            app.pfa = var(2);
            app.range_res = var(3);
            app.tgt_rcs = var(4);
            app.PassLossMarin = var(5);
            app.num_pulse_int = var(6);
            app.fc = var(7);
            app.tx_gain = var(8);
            app.prop_speed = var(9);
            app.pulse_width = var(10);
            app.pulse_bw = var(11);
            app.prf = var(12);
            app.fs = var(13);
            app.peak_power = var(14);
            app.max_range = var(15);
            app.fast_time_grid = unigrid(0,1/app.fs,1/max(app.prf),'[)');
            app.slow_time_grid = (0:app.num_pulse_int-1)/max(app.prf);
        end

        % Button pushed function: LoadSimulationObjectButton
        function LoadSimulationObjectButtonPushed(app, event)
            NoiseFigure = str2num(app.NFEditField.Value);
            [app.reciever,app.transmitter] = create_RxTx(app.tx_gain,NoiseFigure,app.fs,2010,app.peak_power);
            [app.radiator,app.collector] = create_RadColl(app.sArray,app.fc);
            app.channel = create_chan(app.fs,app.fc);
            rec = app.reciever;
            trans = app.transmitter;
            rad = app.radiator;
            coll = app.collector;
            chan = app.channel;
            save('Obj.mat',"chan","coll","rad","trans");
        end

        % Button pushed function: loadAntennaButton
        function loadAntennaButtonPushed(app, event)
            azang = -180:180;
            elang = -90:90;
            gain = 0;
            if(app.Choose_AntennaDropDown.Value == "Antenna1")
                app.sArray = CreateAntenna(azang,elang,gain,app.fc);
            end
            ArrayAntenna = app.sArray;
            save('Antenna.mat',"ArrayAntenna");
        end

        % Button pushed function: ViewAntennaPatternButton
        function ViewAntennaPatternButtonPushed(app, event)
            subplot(1,2,1);patternAzimuth(app.sArray,app.fc,[0],'Type','powerdb');
            subplot(1,2,2);patternElevation(app.sArray,app.fc,[0],'Type','powerdb');
        end

        % Button pushed function: 
        % SetTargetRadarPositionParametersButton
        function SetTargetRadarPositionParametersButtonPushed(app, event)
            TargetRadarParameters;
        end

        % Button pushed function: loadButton_2
        function loadButton_2Pushed(app, event)
            load("Targets.mat","Sensormotion","Tgtmotion","Target");
            app.sensormotion = Sensormotion;
            app.tgtmotion = Tgtmotion;
            app.target = Target;
        end

        % Button pushed function: 
        % configurewaveformandmatchedfilterButton
        function configurewaveformandmatchedfilterButtonPushed(app, event)
            MFandWF;
        end

        % Button pushed function: loadButton_3
        function loadButton_3Pushed(app, event)
            load('WFandMF.mat',"Waveform","Matchingcoeff","Matchedfilter","Tvg");
            app.waveform = Waveform;
            app.matchingcoeff = Matchingcoeff;
            app.matchedfilter = Matchedfilter;
            app.tvg = Tvg;
        end

        % Button pushed function: ConfigureTrackerButton
        function ConfigureTrackerButtonPushed(app, event)
            Tracker;
        end

        % Button pushed function: loadtrackerButton
        function loadtrackerButtonPushed(app, event)
            load("Tracker.mat","TrackerJPDA","PositionSelector","VelocitySelector","Ratio");
            app.tracker = TrackerJPDA;
            app.positionSelector = PositionSelector;
            app.velocitySelector = VelocitySelector;
            app.ratio = Ratio;
        end

        % Button pushed function: LoadTrackerPlotterButton
        function LoadTrackerPlotterButtonPushed(app, event)
            ang1 = [0:360];
            max_rng = app.max_range;
            FontSize = 8;
            app.tp = theaterPlot('XLim',[-app.max_range app.max_range],'YLim',[-app.max_range app.max_range]);
            app.trackp = trackPlotter(app.tp,'DisplayName','Tracks','HistoryDepth',50,'LabelOffset',[0 0 0],'ConnectHistory','on','ColorizeHistory','on' );
            hold on;
                h1 = plot(0.2*max_rng*cosd(ang1),0.2*max_rng*sind(ang1),0.4*max_rng*cosd(ang1),0.4*max_rng*sind(ang1),...
                0.6*max_rng*cosd(ang1),0.6*max_rng*sind(ang1),...
                0.8*max_rng*cosd(ang1),0.8*max_rng*sind(ang1),...
                1*max_rng*cosd(ang1),1*max_rng*sind(ang1),[0 max_rng*cosd(0)],[0 max_rng*sind(0)],[0 max_rng*cosd(30)],[0 max_rng*sind(30)],...
                [0 max_rng*cosd(60)],[0 max_rng*sind(60)],...
                [0 max_rng*cosd(90)],[0 max_rng*sind(90)],...
                [0 max_rng*cosd(120)],[0 max_rng*sind(120)],[0 max_rng*cosd(150)],[0 max_rng*sind(150)],...
                [0 max_rng*cosd(180)],[0 max_rng*sind(180)],[0 max_rng*cosd(210)],[0 max_rng*sind(210)],...
                [0 max_rng*cosd(240)],[0 max_rng*sind(240)],[0 max_rng*cosd(270)],[0 max_rng*sind(270)],...
                [0 max_rng*cosd(300)],[0 max_rng*sind(300)],[0 max_rng*cosd(330)],[0 max_rng*sind(330)]);
            
                set( h1, 'Color', 'r' );
                text(max_rng/sqrt(2)+0.05,max_rng/sqrt(2)+0.05,num2str(max_rng),'FontSize',FontSize);
                text(0.8*max_rng*(1/sqrt(2)+0.05),0.8*max_rng*(1/sqrt(2)+0.05),num2str(max_rng*4/5),'FontSize',FontSize);
                text(0.6*max_rng*(1/sqrt(2)+0.05),0.6*max_rng*(1/sqrt(2)+0.05),num2str(max_rng*3/5),'FontSize',FontSize);
                text(0.4*max_rng*(1/sqrt(2)+0.05),0.4*max_rng*(1/sqrt(2)+0.05),num2str(max_rng*2/5),'FontSize',FontSize);
                text(0.2*max_rng*(1/sqrt(2)+0.05),0.2*max_rng*(1/sqrt(2)+0.05),num2str(max_rng*1/5),'FontSize',FontSize);
            
                text(max_rng+0.05,0,'0','FontSize',FontSize);
                text(max_rng*sqrt(3)/2+0.05,max_rng*0.5+0.05,'30','FontSize',FontSize);
                text(max_rng*0.5+0.05,max_rng*sqrt(3)/2+0.05,'60','FontSize',FontSize);
                text(0,max_rng+0.05,'90','FontSize',FontSize);
                text(-max_rng*sqrt(3)/2-0.1,max_rng*0.5+0.05,'150','FontSize',FontSize);
                text(-max_rng*0.5-0.1,max_rng*sqrt(3)/2+0.05,'120','FontSize',FontSize);
                text(-max_rng-0.1,0,'180','FontSize',FontSize);
                text(-max_rng*sqrt(3)/2-0.1,-max_rng*0.5-0.05,'210','FontSize',FontSize);
                text(-max_rng*0.5-0.05,-max_rng*sqrt(3)/2-0.05,'240','FontSize',FontSize);
                text(0-0.05,-max_rng-0.05,'270','FontSize',FontSize);
                text(max_rng*sqrt(3)/2+0.05,-max_rng*0.5-0.05,'330','FontSize',FontSize);
                text(max_rng*0.5+0.05,-max_rng*sqrt(3)/2-0.05,'300','FontSize',FontSize);
               axis off;
        end

        % Button pushed function: 
        % configureclutterandCFARdetectorButton
        function configureclutterandCFARdetectorButtonPushed(app, event)
            clutterAndCFAR;
        end

        % Button pushed function: loadButton_4
        function loadButton_4Pushed(app, event)
            load("ClutterObj.mat","Clutter","Density","Distortion","Noise_Level","Rain_speed","Rain_steering","RngBinsNum","Speed_axis");
            load("CFARDet.mat","CFAR_Detector","CFAR_Detector1","CFAR_Detector2","Ntraining","Nguard");
            app.clutter = Clutter;
            app.density = Density;
            app.distortion = Distortion;
            app.Noise_level = Noise_Level;
            app.rain_steering = Rain_steering;
            app.rain_speed = Rain_speed;
            app.rngBinsNum = RngBinsNum;
            app.speed_axis = Speed_axis;
            app.cfar_detector = CFAR_Detector;
            app.cfar_detector1 = CFAR_Detector1;
            app.cfar_detector2 = CFAR_Detector2;
            
            app.N_train = Ntraining;
            app.N_guard = Nguard;
        end

        % Button pushed function: beginsimulationButton
        function beginsimulationButtonPushed(app, event)
            % Scan Angles
            range_gates = app.prop_speed*app.fast_time_grid/2;
            save('rrr','range_gates');
            ang = str2num(str2mat(app.anglegridEditField.Value));
            num_360_rotations = str2num(app.numberofrotationsEditField.Value);
            step_time = str2num(app.radarturntimesEditField.Value);
            numscans = numel(ang);
            est_range = [];
            est_angle = [];
            
            int_pulses = zeros(numel(app.fast_time_grid),numscans);
            screens = zeros(numel(app.fast_time_grid),app.num_pulse_int,numscans);
            screens1 = zeros(numel(app.fast_time_grid),numscans,num_360_rotations);
            app.all_range_doppler_maps = [];
            
            fft_size = str2num(app.FFTsizeEditField.Value);
            csig = zeros(numel(app.fast_time_grid),1);
            if(app.withgroundclutterCheckBox.Value == true)
                csig = app.clutter();
            end
            
            processType = app.ProccessingTypeDropDown.Value;
            if(processType == "MTI")
                len = 1;
                D_raw_Rng = {};
                D_raw_Azm = {};
                col = cellstr(num2str([1:len]'))';
                f = figure(3);
                f.Position = [0 300 560 100];
                uit  = uitable('Parent',f,'ColumnName',col,'ColumnWidth','auto','RowName',{'Range';'Azimuth'},'Data',[D_raw_Rng;D_raw_Azm]);
                uit.Position = [0 0 560 100];
                for i = 1:num_360_rotations
                    X = range_gates'*cosd(ang); Y = range_gates'*sind(ang);
                    TH_mat = [];
                    pos = [];
                    for j = 1:numscans
                        
                        release(app.radiator);
                        release(app.collector);
                        release(app.channel);
                        release(app.sArray);
                        release(app.clutter);
                        app.sArray.ElementNormal = [ang(j);10];
                        app.clutter.PlatformDirection = [ang(j);10];
                        app.clutter.PlatformSpeed = 0;
                        
                        for m = 1:app.num_pulse_int
                
                            
                            pulse = app.waveform();
                            % Update sensor and target positions
                            steptime = length(pulse)/app.fs;
                            [sensorpos,sensorvel] = app.sensormotion(steptime);
                            [tgtpos,tgtvel] = app.tgtmotion(steptime);
                
                            % Calculate the target angles as seen by the sensor
                            [~,tgtang] = rangeangle(tgtpos,sensorpos);
                
                            % Simulate propagation of pulse in direction of targets
                            [pulse,txstatus,phsnoise] = app.transmitter(pulse);
                            
                            txsig = app.radiator(pulse,tgtang);
                            
                            txsig = app.channel(txsig,sensorpos,tgtpos,sensorvel,tgtvel);
                
                            % Reflect pulse off of targets
                            tgtsig = app.target(txsig,true);
                            % Receive target returns and clutter at sensor
                            rxsig = app.collector(tgtsig,tgtang);
                
                            rxsig = app.reciever(rxsig+csig,~(txstatus>0),phsnoise);
                
                            % For staggered PRF waveforms, the pulses will be different lengths,
                            % here we truncate to the length of the shortest pulse in the PRF
                            % stagger
                            rxpulses(:,m) = rxsig(1:length(app.fast_time_grid));
                            %Pulse_Map(:,j,m) = rxsig(1:length(fast_time_grid));
                        end
                        rxPulse = rxpulses;
                        mfiltOut = app.matchedfilter(rxPulse);
                        siz = size(mfiltOut,1)*size(mfiltOut,2);
                        matchingdelay = size(app.matchingcoeff,1)-1;
                        mfiltOut = buffer(mfiltOut(matchingdelay+1:siz),size(mfiltOut,1));
                        mfiltOut = app.tvg(mfiltOut);
                        h = [1 -2 1];
                        mtiseq = filter(h,1,mfiltOut,[],2);
                        siz = size(mtiseq,2);
                        mtiseq = pulsint(mtiseq(:,3:siz));
                        
                        plot(app.UIAxes3,range_gates,pow2db(mtiseq));
                        grid(app.UIAxes3,'on');xlabel(app.UIAxes3,'range');
                        ylabel(app.UIAxes3,'Amplitude');axis(app.UIAxes3,[0 app.max_range -150 -25]);
                        mtiseq(1) = mtiseq(2);
                        [x,TH1] = app.cfar_detector(mtiseq,1:length(mtiseq));
                        int_pulses(:,j) = x;
                        screens1(:,j,i) = mtiseq;
                        TH_mat = [TH_mat,TH1];
                
                       % Radar PPI Plot
                        pcolor(app.UIAxes2,X,Y,pow2db(abs(screens1(:,:,i)).^2));
                        shading(app.UIAxes2,'interp');
                        axis(app.UIAxes2,'off');
                        axis(app.UIAxes2,[-app.max_range app.max_range -app.max_range app.max_range]);
                        axis(app.UIAxes2,'equal','tight'); 
                        title(app.UIAxes2,'Radar PPI Display');
                        drawnow;
                    end     
                    % perform next detection when next update is needed
                   	app.sensormotion(step_time);
                    app.tgtmotion(step_time);
                    
                    C_mat = max(screens1(:,:,i) - TH_mat,0);
                    [~,ind_mat] = find_peaks(C_mat);
                    %int_pulses1 = int_pulses(1:end-1,:);
                    [rngInd,angInd] = ind2sub(size(C_mat),ind_mat);
                    est_range = [est_range {range_gates(rngInd)}]; % Estimated range
                    est_angle = [est_angle {ang(angInd)}];    % Estimated direction
                    for k = 1:length(est_range{i})
                        pos = [pos [est_range{i}(k)*cosd(est_angle{i}(k));est_range{i}(k)*sind(est_angle{i}(k));0]];
                    end
                    time = i*step_time;
                    for k = 1:size(pos,2)
                        detection(k) = objectDetection(time,pos(:,k)/app.ratio);
                    end
                    det = detection(1:size(pos,2));
                    tracks = app.tracker(det,time);
                    
                    %State = tracks.State;
                    Len = length(pos);
                    pos = getTrackPositions(tracks,app.positionSelector);
                    vel = getTrackVelocities(tracks,app.velocitySelector);
                    pos = pos*app.ratio;
                    rng = sqrt(pos(:,1).^2 + pos(:,2).^2);
                    az = atand(pos(:,2)./pos(:,1));
                    for i = 1:size(pos,1)
                        if(pos(i,1)<0&&pos(i,2)<0)
                            az(i) = az(i)-180;
                        end
                        if(pos(i,1)<0 && pos(i,2)>0)
                            az(i) = az(i)+180;
                        end
                    end
                    D_raw_Rng = [];
                    D_raw_Azm = [];
                    for i = 1:size(pos,1)
                        D_raw_Rng = [D_raw_Rng,{rng(i)}];
                        D_raw_Azm = [D_raw_Azm,{az(i)}];
                    end
                    len = size(pos,1);
                    col = cellstr(num2str([1:len]'))';
                    uit.ColumnName = col;
                    uit.Data = [D_raw_Rng;D_raw_Azm];

                    vel = vel*app.ratio;
                    labels = string([tracks.TrackID]);
                    figure(1);
                    app.trackp.plotTrack(pos,labels);grid on;
                    if(abs(ang(length(ang))) == abs(ang(1)) && abs(ang(length(ang))) == 180)
                        ang = [180:-1:-180];
                    else
                        if(ang(1) > ang(length(ang)))
                            ang = sort(ang,'ascend');
                        else
                            ang = sort(ang,'descend');
                        end
                    end
                end
                msgbox('Operation Completed');
            else
                prog = 0;
                if(app.datagenerationButton.Value == true)
                    fft_size = str2num(app.FFTsizeEditField.Value);
                    test_sig = zeros(length(range_gates),fft_size,numscans,num_360_rotations);
                    csig = zeros(numel(app.fast_time_grid),1);
                    if(app.withgroundclutterCheckBox.Value == true)
                        csig = app.clutter();
                    end
                    signal = zeros(numel(app.fast_time_grid),fft_size);
                    
                    position = [];
                    vel = [];
                    ang = str2num(str2mat(app.anglegridEditField.Value));
                    for i = 1:num_360_rotations
                        TH_mat = [];
                        pos = [];
                        for j = 1:numscans
                            
                            release(app.radiator);
                            release(app.collector);
                            release(app.channel);
                            release(app.sArray);
                            release(app.clutter);
                            app.sArray.ElementNormal = [ang(j);10];
                            app.clutter.PlatformDirection = [ang(j);10];
                            if(app.withrainclutterCheckBox.Value == true)
                                signal = rain_clutter(app.density,fft_size,app.rngBinsNum,app.rain_speed,...
                                      app.distortion,ang(j),app.rain_steering,app.speed_axis,app.Noise_level);
                            end
                            
                
                            for m = 1:app.num_pulse_int
                    
                                
                                pulse = app.waveform();
                                % Update sensor and target positions
                                steptime = length(pulse)/app.fs;
                                [sensorpos,sensorvel] = app.sensormotion(steptime);
                                [tgtpos,tgtvel] = app.tgtmotion(steptime);
                    
                                % Calculate the target angles as seen by the sensor
                                [~,tgtang] = rangeangle(tgtpos,sensorpos);
                    
                                % Simulate propagation of pulse in direction of targets
                                [pulse,txstatus,phsnoise] = app.transmitter(pulse);
                                
                                txsig = app.radiator(pulse,tgtang);
                                
                                txsig = app.channel(txsig,sensorpos,tgtpos,sensorvel,tgtvel);
                    
                                % Reflect pulse off of targets
                                tgtsig = app.target(txsig,true);
                                csig =  [csig ;zeros(length(txsig) - length(csig),1)];
                          
                    
                                % Receive target returns and clutter at sensor
                                rxsig = app.collector(tgtsig,tgtang);
                    
                                rxsig = app.reciever(rxsig,~(txstatus>0),phsnoise)+csig;
                    
                                % For staggered PRF waveforms, the pulses will be different lengths,
                                % here we truncate to the length of the shortest pulse in the PRF
                                % stagger
                                rxpulses(:,m) = rxsig(1:length(app.fast_time_grid))+signal(:,m);
                                Pulse_Map(:,j,m) = rxsig(1:length(app.fast_time_grid));
                            end
                            rxPulse = rxpulses;
                            mfiltOut = app.matchedfilter(rxPulse);
                            
                            matchingdelay = size(app.matchingcoeff,1)-1;
                            siz = size(mfiltOut,1)*size(mfiltOut,2);
                            mfiltOut = buffer(mfiltOut(matchingdelay+1:siz),size(mfiltOut,1));
                            mfiltOut = app.tvg(mfiltOut);
                            test_sig(:,:,j,i) = mfiltOut;
                          
                        end
                        position = [position {tgtpos}];
                        vel = [vel {tgtvel}];
                        app.sensormotion(step_time);
                        app.tgtmotion(step_time);
                        
                        % perform next detection when next update is needed
                        if(abs(ang(length(ang))) == abs(ang(1) && abs(ang(length(ang))) == 180))
                            ang = [180:-1:-180];
                        else
                            if(ang(1) > ang(length(ang)))
                                ang = sort(ang,'ascend');
                            else
                                ang = sort(ang,'descend');
                            end
                        end
                       	
                        
                    end
                    save("MTD_DATA","test_sig","position","vel");
                    msgbox('Operation Completed');
                    
                end
                if(app.radarprocessingButton.Value == true)
                    ang = str2num(str2mat(app.anglegridEditField.Value));
                    fft_size = str2num(app.FFTsizeEditField.Value);
                    fftsize = fft_size;
                    load('MTD_DATA',"test_sig","position","vel");
                    fd = dop2speed([-app.prf/2 + app.prf/(fft_size) :app.prf/(fft_size):app.prf/2 ],app.waveLength);
                    Z_reports = [];
                    dt = step_time+0.5;
                    vel_from_track = [];
                    screens1 = zeros(numel(app.fast_time_grid),numscans,num_360_rotations);
                    R_D_map = zeros(numel(app.fast_time_grid),fft_size,numscans,num_360_rotations);
                    
                    
                    len = 1;
                    D_raw_Rng = {};
                    D_raw_Azm = {};
                    D_raw_Sp  = {};
                    col = cellstr(num2str([1:len]'))';
                    f = figure(3);
                    f.Position = [0 300 560 100];
                    uit  = uitable('Parent',f,'ColumnName',col,'ColumnWidth','auto','RowName',{'Range';'Azimuth';'speed'},'Data',[D_raw_Rng;D_raw_Azm]);
                    uit.Position = [0 0 560 100];
                    for m = 1:num_360_rotations
                        all_detections = [];
                        peaks = [];
                        ind =[];
                        detection = [];
                        All_pos = [];
                        C_map_sig = zeros(numel(app.fast_time_grid),1);
                        for k = 1:numscans
                            h = [1 -1];
                            mtiseq = filter(h,1,test_sig(:,:,k,m),[],2);
                            F_map = fftshift(abs(fft(test_sig(:,:,k,m),fftsize,2)),2);
                            R_D_map(:,:,k,m) = F_map;
                            C_map_sig = (C_map_sig+abs(F_map(:,size(F_map,2)/2+1)))/8;
                            C_map = C_map_sig.*ones(size(F_map));
                            F = fftshift(abs(fft(mtiseq(:,3:fft_size),fftsize,2)),2);
                            detector1 = app.cfar_detector1;
                    
                            F(numel(app.fast_time_grid),:) = F(numel(app.fast_time_grid)-1,:);
                            [~,CF_TH] = detector1(F,1:numel(app.fast_time_grid));
                            TH = CF_TH;
                            C_Data = max(F - TH,0);
                           
                            detector2 = app.cfar_detector2;
                            [~,CF_TH1] = detector2(C_Data',1:fft_size);
                            TH = CF_TH1;
                            C_Data1 = max(C_Data' - (TH+C_map'),0);
                            CC_DATA = max(C_Data1'-0.5*abs(mean(mean(C_Data1(find(C_Data1~=0))))),0);
                            screens1(:,k,m) = pulsint(F);
                            [~,ind_mat] = find_peaks(CC_DATA);
                            ind = [ind,{ind_mat}];
                            [rngInd,velInd] = ind2sub(size(CC_DATA),ind_mat);
                            peaks = [peaks;{[rngInd;velInd]}];
                            if(length([rngInd;velInd]) ~= 0)
                                detection = [detection,{[rngInd;velInd;k*ones(1,length(rngInd))]}];
                            end
                            
                            plot(app.UIAxes3,range_gates,pow2db(F(:,1)));
                                grid(app.UIAxes3,'on');xlabel(app.UIAxes3,'range');
                                ylabel(app.UIAxes3,'Amplitude');axis(app.UIAxes3,[0 app.max_range -150 -25]);
                    
       
                            X = range_gates'*cosd(ang); Y = range_gates'*sind(ang);
                            % Radar PPI Plot
                            pcolor(app.UIAxes2,X,Y,pow2db(abs(screens1(:,:,m)).^2));
                            axis(app.UIAxes2,'equal','tight'); 
                            shading(app.UIAxes2,'interp');
                            axis(app.UIAxes2,'off');
                            axis(app.UIAxes2,[-app.max_range app.max_range -app.max_range app.max_range]);
                            title(app.UIAxes2,'Radar PPI Display');
                            drawnow;
                            %subplot(2,1,1);pcolor(F);shading interp;colorbar;
                            %subplot(2,1,2);pcolor(CC_DATA);shading interp;colorbar;
                            %pause(0.005)
                        end
                        
                        for i = 1:length(detection)
                            for j = 1:size(detection{i},2)
                                all_detections = [all_detections detection{i}(:,j)];
                    
                            end
                        end
                        [detection] = merging_detections(all_detections);
                        if (length(detection)~=0)
                            Z_reports = [Z_reports,{[range_gates(detection(1,:)).*cosd(ang(detection(3,:)));...
                                range_gates(detection(1,:)).*sind(ang(detection(3,:)));fd(detection(2,:))]}];
                        end
                        
                            for j = 1:size(detection,2)
                                det_pos = [range_gates(detection(1,:)).*cosd(ang(detection(3,:)))...
                                    ;range_gates(detection(1,:)).*sind(ang(detection(3,:)));zeros(1,size(detection,2))];
                            end
                            All_pos = [All_pos {det_pos}];
                            time = m*dt;
                            if(size(det_pos,2) ~=0)
                                
                                   for kn = 1:size(det_pos,2)
                                        detectionn(kn) = objectDetection(time,det_pos(:,kn)/app.ratio);
                                   end
                                    det = detectionn(1:size(det_pos,2));
                                    tracks = app.tracker(det,time);
                    
                                    %State = tracks.State;
                                    Len = length(det_pos);
                                    positions = getTrackPositions(tracks,app.positionSelector);
                                    velocities = getTrackVelocities(tracks,app.velocitySelector);
                                    positions = positions*app.ratio;
                                    velocities = velocities*app.ratio;
                                    vel_from_track = [vel_from_track {velocities}];
                                    labels = string([tracks.TrackID]);
                                    figure(1);
                                    app.trackp.plotTrack(positions,labels);grid on;
                            end
                            if(m >= 2)
                                velocity = vel{m};
                                detection = Z_reports{m};
                                PP = position{m};
                                [vel_est,~,~] = velocity_estimation(positions,detection,PP,velocities,velocity,10); 
                                rng = sqrt(positions(:,1).^2 + positions(:,2).^2);
                                az = atand(positions(:,2)./positions(:,1));
                                for i = 1:size(positions,1)
                                    if(positions(i,1)<0&&positions(i,2)<0)
                                        az(i) = az(i)-180;
                                    end
                                    if(positions(i,1)<0 && positions(i,2)>0)
                                        az(i) = az(i)+180;
                                    end
                                end
                                D_raw_Rng = [];
                                D_raw_Azm = [];
                                D_raw_Sp  = [];
                                for i = 1:size(positions,1)
                                    D_raw_Rng = [D_raw_Rng,{rng(i)}];
                                    D_raw_Azm = [D_raw_Azm,{az(i)}];
                                    D_raw_Sp  = [D_raw_Sp,{vel_est(i)}];
                                end
                                len = size(positions,1);
                                col = cellstr(num2str([1:len]'))';
                                uit.ColumnName = col;
                                uit.Data = [D_raw_Rng;D_raw_Azm;D_raw_Sp];
                            end
                            % perform next detection when next update is needed
                        if(abs(ang(length(ang))) == abs(ang(1)) && abs(ang(length(ang))) == 180)
                            ang = [180:-1:-180];
                        else
                            if(ang(1) > ang(length(ang)))
                                ang = sort(ang,'ascend');
                            else
                                ang = sort(ang,'descend');
                            end
                        end
                    
                    end
                    save('rng_dopp_map.mat','R_D_map');
                    
                end
            end


        end

        % Button pushed function: showRDmapButton
        function showRDmapButtonPushed(app, event)
            range_gates = app.prop_speed*app.fast_time_grid/2;
            ang = str2num(str2mat(app.anglegridEditField.Value));
            num_360_rotations = str2num(app.numberofrotationsEditField.Value);
            step_time = str2num(app.radarturntimesEditField.Value);
            numscans = numel(ang);
            range_doppler_index = str2num(app.doplermapindexEditField.Value);
            fft_size = str2num(app.FFTsizeEditField.Value);
            fftsize = fft_size;
            load("MTD_DATA.mat","test_sig");
            for i = 1:num_360_rotations
                for j = 1:numscans
                    F_map = fftshift(abs(fft(test_sig(:,:,j,i),fftsize,2)),2);
                    figure(2);
                    x = range_gates;
                    y = -app.prf/2:app.prf/size(F_map,2):app.prf/2-app.prf/size(F_map,2);
                    pcolor(abs(F_map));shading interp;xlabel("doppler");ylabel("range");
                    title("range doppler map with angle = " + num2str(ang(j) + " degree"));
                    pause(0.5);
                end
                if(abs(ang(length(ang))) == abs(ang(1)) && abs(ang(length(ang))) == 180)
                    ang = [180:-1:-180];
                else
                    if(ang(1) > ang(length(ang)))
                        ang = sort(ang,'ascend');
                    else
                        ang = sort(ang,'descend');
                    end
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetParametersButton = uibutton(app.UIFigure, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.LoadSimulationObjectButton = uibutton(app.UIFigure, 'push');
            app.LoadSimulationObjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSimulationObjectButtonPushed, true);
            app.LoadSimulationObjectButton.Text = 'Load Simulation Object';
            app.loadAntennaButton = uibutton(app.UIFigure, 'push');
            app.loadAntennaButton.ButtonPushedFcn = createCallbackFcn(app, @loadAntennaButtonPushed, true);
            app.loadAntennaButton.Text = 'load Antenna';
            app.ViewAntennaPatternButton = uibutton(app.UIFigure, 'push');
            app.ViewAntennaPatternButton.ButtonPushedFcn = createCallbackFcn(app, @ViewAntennaPatternButtonPushed, true);
            app.ViewAntennaPatternButton.Text = 'View Antenna Pattern';
            app.SetTargetRadarPositionParametersButton = uibutton(app.UIFigure, 'push');
            app.SetTargetRadarPositionParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetTargetRadarPositionParametersButtonPushed, true);
            app.SetTargetRadarPositionParametersButton.Text = {'Set Target & Radar'; ' Position Parameters'};
            app.loadButton_2 = uibutton(app.UIFigure, 'push');
            app.loadButton_2.ButtonPushedFcn = createCallbackFcn(app, @loadButton_2Pushed, true);
            app.loadButton_2.Text = 'load';
            app.configurewaveformandmatchedfilterButton = uibutton(app.UIFigure, 'push');
            app.configurewaveformandmatchedfilterButton.ButtonPushedFcn = createCallbackFcn(app, @configurewaveformandmatchedfilterButtonPushed, true);
            app.configurewaveformandmatchedfilterButton.Text = {'configure waveform and'; 'matched filter'};
            app.loadButton_3 = uibutton(app.UIFigure, 'push');
            app.loadButton_3.ButtonPushedFcn = createCallbackFcn(app, @loadButton_3Pushed, true);
            app.loadButton_3.Text = 'load';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'PPI')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'A-Scope')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.ConfigureTrackerButton = uibutton(app.UIFigure, 'push');
            app.ConfigureTrackerButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureTrackerButtonPushed, true);
            app.ConfigureTrackerButton.Text = 'Configure Tracker';
            app.loadtrackerButton = uibutton(app.UIFigure, 'push');
            app.loadtrackerButton.ButtonPushedFcn = createCallbackFcn(app, @loadtrackerButtonPushed, true);
            app.loadtrackerButton.Text = 'load tracker';
            app.LoadTrackerPlotterButton = uibutton(app.UIFigure, 'push');
            app.LoadTrackerPlotterButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrackerPlotterButtonPushed, true);
            app.LoadTrackerPlotterButton.Text = {'Load Tracker'; 'Plotter'};
            app.RadarParametersLabel = uilabel(app.UIFigure);
            app.RadarParametersLabel.Text = 'Radar Parameters';
            app.TrackarConfigrationLabel = uilabel(app.UIFigure);
            app.TrackarConfigrationLabel.Text = 'Trackar Configration';
            app.TargetsandEnvironmentLabel = uilabel(app.UIFigure);
            app.TargetsandEnvironmentLabel.Text = 'Targets and Environment';
            app.configureclutterandCFARdetectorButton = uibutton(app.UIFigure, 'push');
            app.configureclutterandCFARdetectorButton.ButtonPushedFcn = createCallbackFcn(app, @configureclutterandCFARdetectorButtonPushed, true);
            app.configureclutterandCFARdetectorButton.Text = {'configure clutter'; 'and CFAR detector'};
            app.loadButton_4 = uibutton(app.UIFigure, 'push');
            app.loadButton_4.ButtonPushedFcn = createCallbackFcn(app, @loadButton_4Pushed, true);
            app.loadButton_4.Text = 'load';
            app.WaveFormConfigrationLabel = uilabel(app.UIFigure);
            app.WaveFormConfigrationLabel.Text = 'WaveForm Configration';
            app.LoadSystemObjectsLabel = uilabel(app.UIFigure);
            app.LoadSystemObjectsLabel.Text = 'Load System Objects';
            app.beginsimulationButton = uibutton(app.UIFigure, 'push');
            app.beginsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @beginsimulationButtonPushed, true);
            app.beginsimulationButton.Text = {'begin'; ' simulation'};
            app.showRDmapButton = uibutton(app.UIFigure, 'push');
            app.showRDmapButton.ButtonPushedFcn = createCallbackFcn(app, @showRDmapButtonPushed, true);
            app.showRDmapButton.Text = 'show RD map';
            app.withgroundclutterCheckBox = uicheckbox(app.UIFigure);
            app.withgroundclutterCheckBox.Text = 'with ground clutter';
            app.withrainclutterCheckBox = uicheckbox(app.UIFigure);
            app.withrainclutterCheckBox.Text = 'with rain clutter';
            app.MTDsimulationstateButtonGroup = uibuttongroup(app.UIFigure);
            app.MTDsimulationstateButtonGroup.Title = 'MTD simulation state';
            app.datagenerationButton = uiradiobutton(app.MTDsimulationstateButtonGroup);
            app.datagenerationButton.Text = 'data generation';
            app.datagenerationButton.Value = true;
            app.radarprocessingButton = uiradiobutton(app.MTDsimulationstateButtonGroup);
            app.radarprocessingButton.Text = 'radar processing';
            app.Choose_AntennaDropDownLabel = uilabel(app.UIFigure);
            app.Choose_AntennaDropDownLabel.Text = 'Choose_Antenna';
            app.Choose_AntennaDropDown = uidropdown(app.UIFigure);
            app.Choose_AntennaDropDown.Items = {'Option 2', 'Option 3', 'Option 4', 'Antenna1'};
            app.Choose_AntennaDropDown.Value = 'Antenna1';
            app.NFEditFieldLabel = uilabel(app.UIFigure);
            app.NFEditFieldLabel.Text = 'NF';
            app.NFEditField = uieditfield(app.UIFigure, 'text');
            app.NFEditField.Value = '0';
            app.ProccessingTypeDropDownLabel = uilabel(app.UIFigure);
            app.ProccessingTypeDropDownLabel.Text = 'Proccessing Type';
            app.ProccessingTypeDropDown = uidropdown(app.UIFigure);
            app.ProccessingTypeDropDown.Items = {'MTI', 'PulseDoppler'};
            app.ProccessingTypeDropDown.Value = 'MTI';
            app.anglegridEditFieldLabel = uilabel(app.UIFigure);
            app.anglegridEditFieldLabel.Text = 'angle grid';
            app.anglegridEditField = uieditfield(app.UIFigure, 'text');
            app.anglegridEditField.Value = '[180:-1:-180]';
            app.numberofrotationsEditFieldLabel = uilabel(app.UIFigure);
            app.numberofrotationsEditFieldLabel.Text = 'number of rotations';
            app.numberofrotationsEditField = uieditfield(app.UIFigure, 'text');
            app.numberofrotationsEditField.Value = '10';
            app.radarturntimesEditFieldLabel = uilabel(app.UIFigure);
            app.radarturntimesEditFieldLabel.Text = 'radar turn time     (s)';
            app.radarturntimesEditField = uieditfield(app.UIFigure, 'text');
            app.radarturntimesEditField.Value = '4';
            app.doplermapindexEditFieldLabel = uilabel(app.UIFigure);
            app.doplermapindexEditFieldLabel.Text = 'dopler map index';
            app.doplermapindexEditField = uieditfield(app.UIFigure, 'text');
            app.FFTsizeEditFieldLabel = uilabel(app.UIFigure);
            app.FFTsizeEditFieldLabel.Text = 'FFT size';
            app.FFTsizeEditField = uieditfield(app.UIFigure, 'text');
            app.FFTsizeEditField.Value = '64';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end