classdef Parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SystemParametersLabel           matlab.ui.control.Label
        SomeOutputLabel                 matlab.ui.control.Label
        detectionpropapilityEditField   matlab.ui.control.EditField
        detectionpropapilityEditFieldLabel  matlab.ui.control.Label
        falseAlarmpropapilityEditField  matlab.ui.control.EditField
        falseAlarmpropapilityEditFieldLabel  matlab.ui.control.Label
        MaxRangemEditField              matlab.ui.control.EditField
        MaxRangemEditFieldLabel         matlab.ui.control.Label
        rangeresolutionmEditField       matlab.ui.control.EditField
        rangeresolutionmEditFieldLabel  matlab.ui.control.Label
        meantargetRCSm2EditField        matlab.ui.control.EditField
        meantargetRCSm2EditFieldLabel   matlab.ui.control.Label
        PassLossMarginWEditField        matlab.ui.control.EditField
        PassLossMarginWEditFieldLabel   matlab.ui.control.Label
        NumberOfintegratedpulsesEditField  matlab.ui.control.EditField
        NumberOfintegratedpulsesEditFieldLabel  matlab.ui.control.Label
        OperatingFrequencyHzEditField   matlab.ui.control.EditField
        OperatingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        AntennaGaindBEditField          matlab.ui.control.EditField
        AntennaGaindBEditFieldLabel     matlab.ui.control.Label
        NeededPowerWEditFieldLabel      matlab.ui.control.Label
        NeededPowerWEditField           matlab.ui.control.EditField
        calculateneededpowerButton      matlab.ui.control.Button
        pulserepeatitionfrequencyHzEditFieldLabel  matlab.ui.control.Label
        pulserepeatitionfrequencyHzEditField  matlab.ui.control.EditField
        pulsewidthmEditFieldLabel       matlab.ui.control.Label
        pulsewidthmEditField            matlab.ui.control.EditField
        pulsebandwidthpulsesweepwidthnumberofcipsHzEditFieldLabel  matlab.ui.control.Label
        pulsebandwidthpulsesweepwidthnumberofcipsHzEditField  matlab.ui.control.EditField
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyHzEditField    matlab.ui.control.EditField
        SwerlingModelDropDownLabel      matlab.ui.control.Label
        SwerlingModelDropDown           matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        % Radar Parameters
        % Radar charactaristic parameters
        pd = 0.9;                              % Probability of detection
        pfa = 1e-6;                            % Probability of false alarm
        max_range = 10000;                     % Maximum unambiguous range
        range_res = 100;                        % Required range resolution
        tgt_rcs = 10;                          % Required target radar cross section
        PassLossMarin = 1000;                   % Power Marin On Pass Loss
        num_pulse_int = 10;                     % Number of integrated pulses
        fc = 10e9;                              % Operating frequency
        tx_gain = 35;
        prop_speed = physconst('LightSpeed');   % Propagation speed; 
        pulse_width = 0;
        pulse_bw = 0;
        prf = 0;
        peak_power = 0;
        fs = 0;
        waveLength = 0;
        
    end
    
    methods (Access = public)
        
        function [pd,pfa,max_range,range_res,tgt_rcs,PassLossMarin,...
                num_pulse_int,fc,tx_gain,prop_speed,pulse_width,pulse_bw,...
                prf,fs] = getParameters(app)
            pd = str2num(app.detectionpropapilityEditField.Value);
            pfa = str2num(app.falseAlarmpropapilityEditField.Value);
            max_range = str2num(app.MaxRangemEditField.Value);
            range_res = str2num(app.rangeresolutionmEditField.Value);
            tgt_rcs = str2num(app.meantargetRCSm2EditField.Value);
            PassLossMarin = str2num(app.PassLossMarginWEditField.Value);
            num_pulse_int = str2num(app.NumberOfintegratedpulsesEditField.Value);
            fc = str2num(app.OperatingFrequencyHzEditField.Value);
            tx_gain = str2num(app.AntennaGaindBEditField.Value);
            pulse_width = str2num(app.pulsewidthmEditField.Value);
            pulse_bw = str2num(app.pulsebandwidthpulsesweepwidthnumberofcipsHzEditField.Value);
            prf = str2num(app.pulserepeatitionfrequencyHzEditField.Value);
            prop_speed = physconst('LightSpeed');   
            fs = str2num(app.SamplingFrequencyHzEditField.Value);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
 
        end

        % Button pushed function: calculateneededpowerButton
        function calculateneededpowerButtonPushed(app, event)
           
            [app.pd,app.pfa,app.max_range,app.range_res,app.tgt_rcs,app.PassLossMarin,...
                app.num_pulse_int,app.fc,app.tx_gain,app.prop_speed,app.pulse_width,app.pulse_bw,app.prf...
                ,app.fs] = getParameters(app);

            app.waveLength = app.prop_speed/app.fc; 
            % The wavelength            
            sw_mod = str2num(app.SwerlingModelDropDown.Value);
            if(sw_mod == 0)
                snr_min = albersheim(app.pd, app.pfa, app.num_pulse_int);
            else
                snr_min = shnidman(app.pd, app.pfa, app.num_pulse_int,sw_mod);
            end
            Needed_peak_power = radareqpow(app.waveLength,app.max_range,snr_min,app.pulse_width,...
                'RCS',app.tgt_rcs,'Gain',app.tx_gain);
            app.NeededPowerWEditField.Value = num2str(Needed_peak_power);
            app.peak_power = Needed_peak_power + app.PassLossMarin;
            var = zeros(1,15);
            var(1) = app.pd;
            var(2) = app.pfa;
            var(3) = app.range_res;
            var(4) = app.tgt_rcs;
            var(5) = app.PassLossMarin;
            var(6) = app.num_pulse_int;
            var(7) = app.fc;
            var(8) = app.tx_gain;
            var(9) = app.prop_speed;
            var(10) = app.pulse_width;
            var(11) = app.pulse_bw;
            var(12) = app.prf;
            var(13) = app.fs;
            var(14) = app.peak_power;
            var(15) = app.max_range;
            save('initialParameters.mat','var');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SystemParametersLabel = uilabel(app.UIFigure);
            app.SystemParametersLabel.Text = 'System Parameters';
            app.SomeOutputLabel = uilabel(app.UIFigure);
            app.SomeOutputLabel.Text = 'Some Output';
            app.detectionpropapilityEditField = uieditfield(app.UIFigure, 'text');
            app.detectionpropapilityEditField.Value = '0.9';
            app.detectionpropapilityEditFieldLabel = uilabel(app.UIFigure);
            app.detectionpropapilityEditFieldLabel.Text = 'detection propapility';
            app.falseAlarmpropapilityEditField = uieditfield(app.UIFigure, 'text');
            app.falseAlarmpropapilityEditField.Value = '1e-6';
            app.falseAlarmpropapilityEditFieldLabel = uilabel(app.UIFigure);
            app.falseAlarmpropapilityEditFieldLabel.Text = 'false Alarm propapility';
            app.MaxRangemEditField = uieditfield(app.UIFigure, 'text');
            app.MaxRangemEditField.Value = '10000';
            app.MaxRangemEditFieldLabel = uilabel(app.UIFigure);
            app.MaxRangemEditFieldLabel.Text = 'Max Range  (m)';
            app.rangeresolutionmEditField = uieditfield(app.UIFigure, 'text');
            app.rangeresolutionmEditField.Value = '100';
            app.rangeresolutionmEditFieldLabel = uilabel(app.UIFigure);
            app.rangeresolutionmEditFieldLabel.Text = 'range resolution    (m)';
            app.meantargetRCSm2EditField = uieditfield(app.UIFigure, 'text');
            app.meantargetRCSm2EditField.Value = '10';
            app.meantargetRCSm2EditFieldLabel = uilabel(app.UIFigure);
            app.meantargetRCSm2EditFieldLabel.Text = 'mean target RCS  (m^2)';
            app.PassLossMarginWEditField = uieditfield(app.UIFigure, 'text');
            app.PassLossMarginWEditField.Value = '2000';
            app.PassLossMarginWEditFieldLabel = uilabel(app.UIFigure);
            app.PassLossMarginWEditFieldLabel.Text = 'Pass Loss Margin    (W)';
            app.NumberOfintegratedpulsesEditField = uieditfield(app.UIFigure, 'text');
            app.NumberOfintegratedpulsesEditField.Value = '10';
            app.NumberOfintegratedpulsesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberOfintegratedpulsesEditFieldLabel.Text = 'Number Of integrated pulses';
            app.OperatingFrequencyHzEditField = uieditfield(app.UIFigure, 'text');
            app.OperatingFrequencyHzEditField.Value = '1e10';
            app.OperatingFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.OperatingFrequencyHzEditFieldLabel.Text = 'Operating Frequency     (Hz)';
            app.AntennaGaindBEditField = uieditfield(app.UIFigure, 'text');
            app.AntennaGaindBEditField.Value = '35';
            app.AntennaGaindBEditFieldLabel = uilabel(app.UIFigure);
            app.AntennaGaindBEditFieldLabel.Text = 'Antenna Gain      (dB)';
            app.NeededPowerWEditFieldLabel = uilabel(app.UIFigure);
            app.NeededPowerWEditFieldLabel.Text = 'Needed Power     (W)';
            app.NeededPowerWEditField = uieditfield(app.UIFigure, 'text');
            app.calculateneededpowerButton = uibutton(app.UIFigure, 'push');
            app.calculateneededpowerButton.ButtonPushedFcn = createCallbackFcn(app, @calculateneededpowerButtonPushed, true);
            app.calculateneededpowerButton.Text = 'calculate needed power';
            app.pulserepeatitionfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.pulserepeatitionfrequencyHzEditFieldLabel.Text = 'pulse repeatition frequency      (Hz)';
            app.pulserepeatitionfrequencyHzEditField = uieditfield(app.UIFigure, 'text');
            app.pulserepeatitionfrequencyHzEditField.Value = '1.498962290000000e+04';
            app.pulsewidthmEditFieldLabel = uilabel(app.UIFigure);
            app.pulsewidthmEditFieldLabel.Text = 'pulse width        (m)';
            app.pulsewidthmEditField = uieditfield(app.UIFigure, 'text');
            app.pulsewidthmEditField.Value = '6.671281903963041e-07';
            app.pulsebandwidthpulsesweepwidthnumberofcipsHzEditFieldLabel = uilabel(app.UIFigure);
            app.pulsebandwidthpulsesweepwidthnumberofcipsHzEditFieldLabel.Text = 'pulse bandwidth/pulse sweepwidth/number of cips         (Hz)';
            app.pulsebandwidthpulsesweepwidthnumberofcipsHzEditField = uieditfield(app.UIFigure, 'text');
            app.pulsebandwidthpulsesweepwidthnumberofcipsHzEditField.Value = '1.498962290000000e+06';
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyHzEditFieldLabel.Text = 'Sampling Frequency     (Hz)';
            app.SamplingFrequencyHzEditField = uieditfield(app.UIFigure, 'text');
            app.SamplingFrequencyHzEditField.Value = '2.997924580000000e+06';
            app.SwerlingModelDropDownLabel = uilabel(app.UIFigure);
            app.SwerlingModelDropDownLabel.Text = 'Swerling Model';
            app.SwerlingModelDropDown = uidropdown(app.UIFigure);
            app.SwerlingModelDropDown.Items = {'0', '1', '2', '3', '4'};
            app.SwerlingModelDropDown.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end