classdef TargetRadarParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TargetsPositionsEditFieldLabel  matlab.ui.control.Label
        TargetsPositionsEditField       matlab.ui.control.EditField
        TargetVelEditFieldLabel         matlab.ui.control.Label
        TargetVelEditField              matlab.ui.control.EditField
        TargetAccEditFieldLabel         matlab.ui.control.Label
        TargetAccEditField              matlab.ui.control.EditField
        TargetRCSEditFieldLabel         matlab.ui.control.Label
        TargetRCSEditField              matlab.ui.control.EditField
        SenseorhightEditFieldLabel      matlab.ui.control.Label
        SenseorhightEditField           matlab.ui.control.EditField
        CreatePlatformaButton           matlab.ui.control.Button
        OperatingfrequencyEditFieldLabel  matlab.ui.control.Label
        OperatingfrequencyEditField     matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        testtimeEditFieldLabel          matlab.ui.control.Label
        testtimeEditField               matlab.ui.control.EditField
        TestButton                      matlab.ui.control.Button
        teststeptimeEditFieldLabel      matlab.ui.control.Label
        teststeptimeEditField           matlab.ui.control.EditField
        maxrangeEditFieldLabel          matlab.ui.control.Label
        maxrangeEditField               matlab.ui.control.EditField
        SwerlingModelsDropDownLabel     matlab.ui.control.Label
        SwerlingModelsDropDown          matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        tgtpos = [[700 0 400]',[2000 500 400]',[-5000 3000 500]',[7800 -1980 300]',[-5800 -5000 300]',[-4300 -4000 300]'];
        Acceleration = [[7 -8 0]',[5 0 0]',[-2 3 0]',[1 -1 0]',[-9 +3 0]',[3 -4 0]'];
        tgtvel = [[50 0 0]',[-40 90 0]',[-45 55 0]',[40 -35 0]',[30 -25 0]',[32 -14 0]'];
        tgtrcs = [1 2.2 1.1 1.05 1.2 1.09];
        fc = 1e10;
        sensorhight = 10;
        sensormotion = [];
        tgtmotion = [];
        target = [];
        tstTime = 40;
        tststepTime = 4;
        max_range = 10000;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreatePlatformaButton
        function CreatePlatformaButtonPushed(app, event)
            app.tgtpos = str2num(str2mat(app.TargetsPositionsEditField.Value));
            app.Acceleration = str2num(str2mat(app.TargetAccEditField.Value));
            app.tgtvel = str2num(str2mat(app.TargetVelEditField.Value));
            app.tgtrcs = str2num(str2mat(app.TargetRCSEditField.Value));
            app.sensorhight = str2num(app.SenseorhightEditField.Value);
            app.fc = str2num(app.OperatingfrequencyEditField.Value);
            app.sensormotion = phased.Platform(...
                'InitialPosition',[0; 0; app.sensorhight],...
                'Velocity',[0; 0; 0]);
            app.tgtmotion = phased.Platform('MotionModel','Acceleration','InitialPosition',...
                app.tgtpos,'InitialVelocity',app.tgtvel,'Acceleration',app.Acceleration);
            if(app.SwerlingModelsDropDown.Value == "Swerling0")
                app.target = phased.RadarTarget('MeanRCS',app.tgtrcs,'Model','Nonfluctuating',...
                    'OperatingFrequency',app.fc,'SeedSource','Property','Seed',2007);
            else
                app.target = phased.RadarTarget('MeanRCS',app.tgtrcs,'Model',app.SwerlingModelsDropDown.Value,...
                    'OperatingFrequency',app.fc,'SeedSource','Property','Seed',2007);
            end
            Sensormotion = app.sensormotion;
            Tgtmotion = app.tgtmotion;
            Target = app.target;
            save('Targets.mat',"Sensormotion","Tgtmotion","Target");
            
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            cla(app.UIAxes,'reset')
            app.tstTime = str2num(app.testtimeEditField.Value);
            app.tststepTime = str2num(app.teststeptimeEditField.Value);
            app.max_range = str2num(app.maxrangeEditField.Value);
            ang1 = [0:360];
            FontSize = 12;
            max_rng = app.max_range;
            pos = [];
            vel = [];
            for i = 1:ceil(app.tstTime/app.tststepTime)
                [app.tgtpos,app.tgtvel] = app.tgtmotion(app.tststepTime);
                pos1 = [];
                vel1 = [];
                for j = 1:size(app.tgtpos,2)
                    pos1 = [pos1;{[app.tgtpos(1,j) app.tgtpos(2,j)]}];
                    vel1 = [vel1;{app.tgtvel(1,j) app.tgtvel(2,j)}];
                end
                pos = [pos pos1];
                vel = [vel vel1];
    
            end
            
            x_axis = zeros(size(pos));
            y_axis = zeros(size(pos));
            for i = 1:size(pos,1)
                for j = 1:size(pos,2)
                    x_axis(i,j) = pos{i,j}(1);
                    y_axis(i,j) = pos{i,j}(2);
        
                end
            end
            for i = 1:size(pos,1)
                plot(app.UIAxes,x_axis(i,:),y_axis(i,:),'*');
                hold(app.UIAxes,'on');
                
            end
            
            h1 = plot(app.UIAxes,0.2*max_rng*cosd(ang1),0.2*max_rng*sind(ang1),0.4*max_rng*cosd(ang1),0.4*max_rng*sind(ang1),...
            0.6*max_rng*cosd(ang1),0.6*max_rng*sind(ang1),...
            0.8*max_rng*cosd(ang1),0.8*max_rng*sind(ang1),...
            1*max_rng*cosd(ang1),1*max_rng*sind(ang1),[0 max_rng*cosd(0)],[0 max_rng*sind(0)],[0 max_rng*cosd(30)],[0 max_rng*sind(30)],...
            [0 max_rng*cosd(60)],[0 max_rng*sind(60)],...
            [0 max_rng*cosd(90)],[0 max_rng*sind(90)],...
            [0 max_rng*cosd(120)],[0 max_rng*sind(120)],[0 max_rng*cosd(150)],[0 max_rng*sind(150)],...
            [0 max_rng*cosd(180)],[0 max_rng*sind(180)],[0 max_rng*cosd(210)],[0 max_rng*sind(210)],...
            [0 max_rng*cosd(240)],[0 max_rng*sind(240)],[0 max_rng*cosd(270)],[0 max_rng*sind(270)],...
            [0 max_rng*cosd(300)],[0 max_rng*sind(300)],[0 max_rng*cosd(330)],[0 max_rng*sind(330)]);
        
            set( h1, 'Color', 'r' );
            text(app.UIAxes,max_rng/sqrt(2)+0.05,max_rng/sqrt(2)+0.05,num2str(max_rng),'FontSize',FontSize);
            text(app.UIAxes,0.8*max_rng*(1/sqrt(2)+0.05),0.8*max_rng*(1/sqrt(2)+0.05),num2str(max_rng*4/5),'FontSize',FontSize);
            text(app.UIAxes,0.6*max_rng*(1/sqrt(2)+0.05),0.6*max_rng*(1/sqrt(2)+0.05),num2str(max_rng*3/5),'FontSize',FontSize);
            text(app.UIAxes,0.4*max_rng*(1/sqrt(2)+0.05),0.4*max_rng*(1/sqrt(2)+0.05),num2str(max_rng*2/5),'FontSize',FontSize);
            text(app.UIAxes,0.2*max_rng*(1/sqrt(2)+0.05),0.2*max_rng*(1/sqrt(2)+0.05),num2str(max_rng*1/5),'FontSize',FontSize);
        
            text(app.UIAxes,max_rng+0.05,0,'0','FontSize',FontSize);
            text(app.UIAxes,max_rng*sqrt(3)/2+0.05,max_rng*0.5+0.05,'30','FontSize',FontSize);
            text(app.UIAxes,max_rng*0.5+0.05,max_rng*sqrt(3)/2+0.05,'60','FontSize',FontSize);
            text(app.UIAxes,0,max_rng+0.05,'90','FontSize',FontSize);
            text(app.UIAxes,-max_rng*sqrt(3)/2-0.1,max_rng*0.5-0.05,'150','FontSize',FontSize);
            text(app.UIAxes,-max_rng*0.5-0.1,max_rng*sqrt(3)/2+0.05,'120','FontSize',FontSize);
            text(app.UIAxes,-max_rng-0.1,0,'180','FontSize',FontSize);
            text(app.UIAxes,-max_rng*sqrt(3)/2-0.1,-max_rng*0.5+0.05,'210','FontSize',FontSize);
            text(app.UIAxes,-max_rng*0.5-0.05,-max_rng*sqrt(3)/2+0.05,'240','FontSize',FontSize);
            text(app.UIAxes,0-0.05,-max_rng+0.05,'270','FontSize',FontSize);
            text(app.UIAxes,max_rng*sqrt(3)/2+0.05,-max_rng*0.5+0.05,'330','FontSize',FontSize);
            text(app.UIAxes,max_rng*0.5+0.05,-max_rng*sqrt(3)/2+0.05,'300','FontSize',FontSize);
            axis(app.UIAxes,[-max_rng max_rng -max_rng max_rng]);
            axis(app.UIAxes,'off');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TargetsPositionsEditFieldLabel = uilabel(app.UIFigure);
            app.TargetsPositionsEditFieldLabel.Text = 'Targets Positions';
            app.TargetsPositionsEditField = uieditfield(app.UIFigure, 'text');
            app.TargetsPositionsEditField.Value = '[[700 0 400]'',[2000 500 400]'',[-5000 3000 500]'',[7800 -1980 300]'',[-5800 -5000 300]'',[-4300 -4000 300]'']';
            app.TargetVelEditFieldLabel = uilabel(app.UIFigure);
            app.TargetVelEditFieldLabel.Text = 'Target Vel';
            app.TargetVelEditField = uieditfield(app.UIFigure, 'text');
            app.TargetVelEditField.Value = '[[50 0 0]'',[-40 90 0]'',[-45 55 0]'',[40 -35 0]'',[30 -25 0]'',[32 -14 0]'']';
            app.TargetAccEditFieldLabel = uilabel(app.UIFigure);
            app.TargetAccEditFieldLabel.Text = 'Target Acc';
            app.TargetAccEditField = uieditfield(app.UIFigure, 'text');
            app.TargetAccEditField.Value = '[[7 -8 0]'',[5 0 0]'',[-2 3 0]'',[1 -1 0]'',[-9 +3 0]'',[3 -4 0]'']';
            app.TargetRCSEditFieldLabel = uilabel(app.UIFigure);
            app.TargetRCSEditFieldLabel.Text = 'Target RCS';
            app.TargetRCSEditField = uieditfield(app.UIFigure, 'text');
            app.TargetRCSEditField.Value = '[1 2.2 1.1 1.05 1.2 1.09]';
            app.SenseorhightEditFieldLabel = uilabel(app.UIFigure);
            app.SenseorhightEditFieldLabel.Text = 'Senseor hight';
            app.SenseorhightEditField = uieditfield(app.UIFigure, 'text');
            app.SenseorhightEditField.Value = '40';
            app.CreatePlatformaButton = uibutton(app.UIFigure, 'push');
            app.CreatePlatformaButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePlatformaButtonPushed, true);
            app.CreatePlatformaButton.Text = 'Create Platforma';
            app.OperatingfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.OperatingfrequencyEditFieldLabel.Text = 'Operating frequency';
            app.OperatingfrequencyEditField = uieditfield(app.UIFigure, 'text');
            app.OperatingfrequencyEditField.Value = '1e10';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Targets Display')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.testtimeEditFieldLabel = uilabel(app.UIFigure);
            app.testtimeEditFieldLabel.Text = 'test  time';
            app.testtimeEditField = uieditfield(app.UIFigure, 'text');
            app.testtimeEditField.Value = '40';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.teststeptimeEditFieldLabel = uilabel(app.UIFigure);
            app.teststeptimeEditFieldLabel.Text = 'test steptime';
            app.teststeptimeEditField = uieditfield(app.UIFigure, 'text');
            app.teststeptimeEditField.Value = '4';
            app.maxrangeEditFieldLabel = uilabel(app.UIFigure);
            app.maxrangeEditFieldLabel.Text = 'max range';
            app.maxrangeEditField = uieditfield(app.UIFigure, 'text');
            app.maxrangeEditField.Value = '10000';
            app.SwerlingModelsDropDownLabel = uilabel(app.UIFigure);
            app.SwerlingModelsDropDownLabel.Text = 'Swerling Models';
            app.SwerlingModelsDropDown = uidropdown(app.UIFigure);
            app.SwerlingModelsDropDown.Items = {'Swerling0', 'Swerling1', 'Swerling2', 'Swerling3', 'Swerling4'};
            app.SwerlingModelsDropDown.Value = 'Swerling0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TargetRadarParameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end