classdef Tracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChooseFilterDropDownLabel       matlab.ui.control.Label
        ChooseFilterDropDown            matlab.ui.control.DropDown
        maxnumberoftracksEditFieldLabel  matlab.ui.control.Label
        maxnumberoftracksEditField      matlab.ui.control.EditField
        gatemEditFieldLabel             matlab.ui.control.Label
        gatemEditField                  matlab.ui.control.EditField
        newtargetdensityEditFieldLabel  matlab.ui.control.Label
        newtargetdensityEditField       matlab.ui.control.EditField
        detectionpropapilityEditFieldLabel  matlab.ui.control.Label
        detectionpropapilityEditField   matlab.ui.control.EditField
        falsealarmpropabilityEditFieldLabel  matlab.ui.control.Label
        falsealarmpropabilityEditField  matlab.ui.control.EditField
        ConfirmationThresholdEditFieldLabel  matlab.ui.control.Label
        ConfirmationThresholdEditField  matlab.ui.control.EditField
        DeletionThresholdEditFieldLabel  matlab.ui.control.Label
        DeletionThresholdEditField      matlab.ui.control.EditField
        TimeToleranceEditFieldLabel     matlab.ui.control.Label
        TimeToleranceEditField          matlab.ui.control.EditField
        CreateButton                    matlab.ui.control.Button
        ratioEditFieldLabel             matlab.ui.control.Label
        ratioEditField                  matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            ratio = str2num(app.ratioEditField.Value);
            numTracks = str2num(app.maxnumberoftracksEditField.Value);
            gate = str2num(app.gatemEditField.Value)/ratio;
            beta = str2num(app.newtargetdensityEditField.Value);
            pd = str2num(app.detectionpropapilityEditField.Value);
            far = str2num(app.falsealarmpropabilityEditField.Value);
            timTOL = str2num(app.TimeToleranceEditField.Value);
            confTH = str2num(app.ConfirmationThresholdEditField.Value);
            delTH = str2num(app.DeletionThresholdEditField.Value);
            vol = 1e9;
            Type = app.ChooseFilterDropDown.Value;
            if(Type == "ImmFilt")
                positionSelector = [1 0 0 0 0 0 ;0 0 1 0 0 0; 0 0 0 0 0 0]; % [x, y, 0]
                velocitySelector = [0 1 0 0 0 0 ; 0 0 0 1 0 0 ;0 0 0 0 0 0]; % [vx, vy, 0]
                
                tracker = trackerJPDA(...
                    'FilterInitializationFcn',@initekfimm,...
                    'MaxNumTracks', numTracks, ...
                    'MaxNumSensors', 1, ...
                    'AssignmentThreshold',gate, ...
                    'DeathRate',0.01,...
                    'TrackLogic','Integrated',...
                    'DetectionProbability', pd, ...
                    'ConfirmationThreshold',confTH,...
                    'DeletionThreshold',delTH,...
                    'ClutterDensity', far/vol, ...
                    'NewTargetDensity', beta,...
                    'TimeTolerance',timTOL);
            elseif(Type == "CVABFilte")
                positionSelector = [1 0 0 0 0 0 ;0 0 1 0 0 0; 0 0 0 0 0 0]; % [x, y, 0]
                velocitySelector = [0 1 0 0 0 0 ; 0 0 0 1 0 0 ;0 0 0 0 0 0]; % [vx, vy, 0]
                
                tracker = trackerJPDA(...
                    'FilterInitializationFcn',@initcvabf,...
                    'MaxNumTracks', numTracks, ...
                    'MaxNumSensors', 1, ...
                    'AssignmentThreshold',gate, ...
                    'DeathRate',0.01,...
                    'TrackLogic','Integrated',...
                    'DetectionProbability', pd, ...
                    'ConfirmationThreshold',confTH,...
                    'DeletionThreshold',delTH,...
                    'ClutterDensity', far/vol, ...
                    'NewTargetDensity', beta,...
                    'TimeTolerance',timTOL);
                elseif(Type == "CVKFilt")
                    positionSelector = [1 0 0 0 0 0 ;0 0 1 0 0 0; 0 0 0 0 0 0]; % [x, y, 0]
                    velocitySelector = [0 1 0 0 0 0 ; 0 0 0 1 0 0 ;0 0 0 0 0 0]; % [vx, vy, 0]
                    
                    tracker = trackerJPDA(...
                        'FilterInitializationFcn',@initcvukf,...
                        'MaxNumTracks', numTracks, ...
                        'MaxNumSensors', 1, ...
                        'AssignmentThreshold',gate, ...
                        'DeathRate',0.01,...
                        'TrackLogic','Integrated',...
                        'DetectionProbability', pd, ...
                        'ConfirmationThreshold',confTH,...
                        'DeletionThreshold',delTH,...
                        'ClutterDensity', far/vol, ...
                        'NewTargetDensity', beta,...
                        'TimeTolerance',timTOL);
            elseif(Type == "CAABFilt")
                    positionSelector = [1 0 0 0 0 0 0 0 0 ;0 0 0 1 0 0 0 0 0; 0 0 0 0 0 0 0 0 0]; % [x, y, 0]
                    velocitySelector = [0 0 1 0 0 0 0 0 0; 0 0 0 0 1 0 0 0 0 ;0 0 0 0 0 0 0 0 0]; % [vx, vy, 0]
                    
                    tracker = trackerJPDA(...
                        'FilterInitializationFcn',@initcaabf,...
                        'MaxNumTracks', numTracks, ...
                        'MaxNumSensors', 1, ...
                        'AssignmentThreshold',gate, ...
                        'DeathRate',0.01,...
                        'TrackLogic','Integrated',...
                        'DetectionProbability', pd, ...
                        'ConfirmationThreshold',confTH,...
                        'DeletionThreshold',delTH,...
                        'ClutterDensity', far/vol, ...
                        'NewTargetDensity', beta,...
                        'TimeTolerance',timTOL);
            elseif(Type == "CAKFilt")
                positionSelector = [1 0 0 0 0 0 0 0 0 ;0 0 0 1 0 0 0 0 0; 0 0 0 0 0 0 0 0 0]; % [x, y, 0]
                velocitySelector = [0 0 1 0 0 0 0 0 0; 0 0 0 0 1 0 0 0 0 ;0 0 0 0 0 0 0 0 0]; % [vx, vy, 0]
                    
                    tracker = trackerJPDA(...
                        'FilterInitializationFcn',@initcaukf,...
                        'MaxNumTracks', numTracks, ...
                        'MaxNumSensors', 1, ...
                        'AssignmentThreshold',gate, ...
                        'DeathRate',0.01,...
                        'TrackLogic','Integrated',...
                        'DetectionProbability', pd, ...
                        'ConfirmationThreshold',confTH,...
                        'DeletionThreshold',delTH,...
                        'ClutterDensity', far/vol, ...
                        'NewTargetDensity', beta,...
                        'TimeTolerance',timTOL);
            end
            PositionSelector = positionSelector;
            VelocitySelector = velocitySelector;
            Ratio = ratio;
            TrackerJPDA = tracker;
            save('Tracker.mat',"TrackerJPDA","PositionSelector","VelocitySelector","Ratio");
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseFilterDropDownLabel = uilabel(app.UIFigure);
            app.ChooseFilterDropDownLabel.Text = 'Choose Filter';
            app.ChooseFilterDropDown = uidropdown(app.UIFigure);
            app.ChooseFilterDropDown.Items = {'ImmFilt', 'CVABFilte', 'CAABFilt', 'CVKFilt', 'CAKFilt'};
            app.ChooseFilterDropDown.Value = 'ImmFilt';
            app.maxnumberoftracksEditFieldLabel = uilabel(app.UIFigure);
            app.maxnumberoftracksEditFieldLabel.Text = 'max number of tracks';
            app.maxnumberoftracksEditField = uieditfield(app.UIFigure, 'text');
            app.maxnumberoftracksEditField.Value = '100';
            app.gatemEditFieldLabel = uilabel(app.UIFigure);
            app.gatemEditFieldLabel.Text = 'gate    (m)';
            app.gatemEditField = uieditfield(app.UIFigure, 'text');
            app.gatemEditField.Value = '800';
            app.newtargetdensityEditFieldLabel = uilabel(app.UIFigure);
            app.newtargetdensityEditFieldLabel.Text = 'new target density';
            app.newtargetdensityEditField = uieditfield(app.UIFigure, 'text');
            app.newtargetdensityEditField.Value = '1e-14';
            app.detectionpropapilityEditFieldLabel = uilabel(app.UIFigure);
            app.detectionpropapilityEditFieldLabel.Text = 'detection propapility';
            app.detectionpropapilityEditField = uieditfield(app.UIFigure, 'text');
            app.detectionpropapilityEditField.Value = '0.9';
            app.falsealarmpropabilityEditFieldLabel = uilabel(app.UIFigure);
            app.falsealarmpropabilityEditFieldLabel.Text = 'false alarm propability';
            app.falsealarmpropabilityEditField = uieditfield(app.UIFigure, 'text');
            app.falsealarmpropabilityEditField.Value = '1e-6';
            app.ConfirmationThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ConfirmationThresholdEditFieldLabel.Text = 'ConfirmationThreshold';
            app.ConfirmationThresholdEditField = uieditfield(app.UIFigure, 'text');
            app.ConfirmationThresholdEditField.Value = '0.95';
            app.DeletionThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.DeletionThresholdEditFieldLabel.Text = 'DeletionThreshold';
            app.DeletionThresholdEditField = uieditfield(app.UIFigure, 'text');
            app.DeletionThresholdEditField.Value = '0.2';
            app.TimeToleranceEditFieldLabel = uilabel(app.UIFigure);
            app.TimeToleranceEditFieldLabel.Text = 'TimeTolerance';
            app.TimeToleranceEditField = uieditfield(app.UIFigure, 'text');
            app.TimeToleranceEditField.Value = '10';
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.ratioEditFieldLabel = uilabel(app.UIFigure);
            app.ratioEditFieldLabel.Text = 'ratio';
            app.ratioEditField = uieditfield(app.UIFigure, 'text');
            app.ratioEditField.Value = '10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end