classdef clutterAndCFAR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClutterConfigrationLabel        matlab.ui.control.Label
        cluttergammamodelDropDownLabel  matlab.ui.control.Label
        cluttergammamodelDropDown       matlab.ui.control.DropDown
        EarthModelDropDownLabel         matlab.ui.control.Label
        EarthModelDropDown              matlab.ui.control.DropDown
        AzimuthCoveragedegreeEditFieldLabel  matlab.ui.control.Label
        AzimuthCoveragedegreeEditField  matlab.ui.control.EditField
        AzemuthBeamwidthdegreeEditFieldLabel  matlab.ui.control.Label
        AzemuthBeamwidthdegreeEditField  matlab.ui.control.EditField
        RadarHeightmEditFieldLabel      matlab.ui.control.Label
        RadarHeightmEditField           matlab.ui.control.EditField
        createButton                    matlab.ui.control.Button
        plotdopplerazimuthresponseButton  matlab.ui.control.Button
        CFARConfigrationLabel           matlab.ui.control.Label
        numberoftrainingcellsEditFieldLabel  matlab.ui.control.Label
        numberoftrainingcellsEditField  matlab.ui.control.EditField
        numberofguardcellsEditFieldLabel  matlab.ui.control.Label
        numberofguardcellsEditField     matlab.ui.control.EditField
        ThresholdFactorLabel            matlab.ui.control.Label
        ThresholdFactorSlider           matlab.ui.control.Slider
        falsealarmprobabilityEditFieldLabel  matlab.ui.control.Label
        falsealarmprobabilityEditField  matlab.ui.control.EditField
        createButton_2                  matlab.ui.control.Button
        RadarspeedmsEditFieldLabel      matlab.ui.control.Label
        RadarspeedmsEditField           matlab.ui.control.EditField
        loadparametersButton            matlab.ui.control.Button
        CFARTypeDropDownLabel           matlab.ui.control.Label
        CFARTypeDropDown                matlab.ui.control.DropDown
        depressionangledegreeEditFieldLabel  matlab.ui.control.Label
        depressionangledegreeEditField  matlab.ui.control.EditField
        CFARAlgorithmDropDownLabel      matlab.ui.control.Label
        CFARAlgorithmDropDown           matlab.ui.control.DropDown
        GroundClutterLabel              matlab.ui.control.Label
        RainClutterLabel                matlab.ui.control.Label
        rainspeedmsEditFieldLabel       matlab.ui.control.Label
        rainspeedmsEditField            matlab.ui.control.EditField
        rainsteeringdegreeEditFieldLabel  matlab.ui.control.Label
        rainsteeringdegreeEditField     matlab.ui.control.EditField
        densityEditFieldLabel           matlab.ui.control.Label
        densityEditField                matlab.ui.control.EditField
        noiseleveldBEditFieldLabel      matlab.ui.control.Label
        noiseleveldBEditField           matlab.ui.control.EditField
        showrangedopplerresponseButton  matlab.ui.control.Button
        fftsizeEditFieldLabel           matlab.ui.control.Label
        fftsizeEditField                matlab.ui.control.EditField
        rangebinsnumEditFieldLabel      matlab.ui.control.Label
        rangebinsnumEditField           matlab.ui.control.EditField
        distortionEditFieldLabel        matlab.ui.control.Label
        distortionEditField             matlab.ui.control.EditField
    end

    
    properties (Access = public)
        % Radar Parameters
        % Radar charactaristic parameters
        pd = 0.9;                              % Probability of detection
        pfa = 1e-6;                            % Probability of false alarm
        max_range = 10000;                     % Maximum unambiguous range
        range_res = 100;                        % Required range resolution
        tgt_rcs = 10;                          % Required target radar cross section
        PassLossMarin = 1000;                   % Power Marin On Pass Loss
        num_pulse_int = 10;                     % Number of integrated pulses
        fc = 10e9;                              % Operating frequency
        tx_gain = 35;
        prop_speed = physconst('LightSpeed');   % Propagation speed; 
        pulse_width = 10e-6;
        pulse_bw = 1e5;
        prf = 15000;
        peak_power = 2000;
        fs = 2e5;
        waveLength = 3e8/(1039);
        sArray = [];
        clutter = [];
        cFar = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadparametersButton
        function loadparametersButtonPushed(app, event)
            load('initialParameters.mat',"var");
            app.pd = var(1);
            app.pfa = var(2);
            app.range_res = var(3);
            app.tgt_rcs = var(4);
            app.PassLossMarin = var(5);
            app.num_pulse_int = var(6);
            app.fc = var(7);
            app.tx_gain = var(8);
            app.prop_speed = var(9);
            app.pulse_width = var(10);
            app.pulse_bw = var(11);
            app.prf = var(12);
            app.fs = var(13);
            app.peak_power = var(14);
            
        end

        % Button pushed function: createButton
        function createButtonPushed(app, event)
            load("Antenna.mat","ArrayAntenna");
            app.sArray = ArrayAntenna;
            gammaType = app.cluttergammamodelDropDown.Value;
            terrainType = app.EarthModelDropDown.Value;
            AzCov = str2num(app.AzimuthCoveragedegreeEditField.Value);
            AzBW = str2num(app.AzemuthBeamwidthdegreeEditField.Value);
            RadarH = str2num(app.RadarHeightmEditField.Value);
            RadarSpeed = str2num(app.RadarspeedmsEditField.Value);
            depAng = str2num(app.depressionangledegreeEditField.Value);
            if(gammaType == "flatLand")
                trgamma = surfacegamma('flatland',app.fc);
            elseif(gammaType == "woods")
                trgamma = surfacegamma('woods',app.fc);
            elseif(gammaType == "seaState")
                trgamma = surfacegamma('sea state 5',app.fc);
            elseif(gammaType == "Rain")
                trgamma = surfacegamma('farmland',app.fc);
            end
            

            app.clutter = phased.ConstantGammaClutter('Sensor',app.sArray,...
                'PropagationSpeed',app.prop_speed,'EarthModel',terrainType,...
                'OperatingFrequency',app.fc,'TransmitERP',app.peak_power,...
                'SampleRate',app.fs,'TransmitSignalInputPort',false,...
                'PRF',app.prf,'Gamma',trgamma,'PlatformHeight',RadarH,...
                'PlatformSpeed',RadarSpeed,'PlatformDirection',[0;0],...
                'BroadsideDepressionAngle',depAng,'MaximumRange',app.max_range,...
                'AzimuthCoverage',AzCov,'PatchAzimuthWidth',AzBW,...
                'SeedSource','Property','Seed',2011);
            fft_size = str2num(app.fftsizeEditField.Value);
            Clutter = app.clutter;
            Rain_speed = str2num(app.rainspeedmsEditField.Value);
            Density    = str2num(app.densityEditField.Value);
            RngBinsNum = str2num(app.rangebinsnumEditField.Value);
            Rain_steering = str2num(app.rainsteeringdegreeEditField.Value);
            Noise_Level = str2num(app.noiseleveldBEditField.Value);
            Distortion = str2num(app.distortionEditField.Value);
            Speed_axis = dop2speed([-app.prf/2 + app.prf/fft_size:app.prf/fft_size:app.prf/2],app.prop_speed/app.fc) ;
            save("ClutterObj.mat","Clutter","Rain_speed","Density","Rain_steering","Distortion","Noise_Level","RngBinsNum","Speed_axis");
        end

        % Button pushed function: plotdopplerazimuthresponseButton
        function plotdopplerazimuthresponseButtonPushed(app, event)
            Nsamp = app.fs/app.prf;
            Npulse = 10;
            sig = zeros(Nsamp,Npulse);
            for m = 1:Npulse
                sig(:,m) = app.clutter();
            end
            
            response = phased.AngleDopplerResponse('SensorArray',app.sArray,...
                'OperatingFrequency',app.fc,'PropagationSpeed',app.prop_speed,'PRF',app.prf);
            plotResponse(response,shiftdim(sig(1,:)),'NormalizeDoppler',true)
        end

        % Button pushed function: createButton_2
        function createButton_2Pushed(app, event)
            CFARType = app.CFARTypeDropDown.Value;
            Ntraining = str2num(str2mat(app.numberoftrainingcellsEditField.Value));
            Nguard = str2num(str2mat(app.numberofguardcellsEditField.Value));
            Pfa_goal = app.pfa;
            AlgType = app.CFARAlgorithmDropDown.Value;
            THFac = app.ThresholdFactorSlider.Value;
            detector = [];
            detector1= [];
            detector2= [];
            if(CFARType == "1D")
                % CFAR Detector
                detector = phased.CFARDetector('Method',AlgType,...
                    'NumTrainingCells',Ntraining(1),'NumGuardCells',Nguard(1),...
                    'ProbabilityFalseAlarm',Pfa_goal,'ThresholdOutputPort',true,...
                    'ThresholdFactor','custom',...
                  'CustomThresholdFactor',THFac);
              
            elseif(CFARType == "2D")
                detector1 = phased.CFARDetector('Method',AlgType,...
                    'NumTrainingCells',Ntraining(1),'NumGuardCells',Nguard(1),...
                    'ProbabilityFalseAlarm',Pfa_goal,'ThresholdOutputPort',true,...
                    'ThresholdFactor','custom',...
                  'CustomThresholdFactor',THFac);
                detector2 = phased.CFARDetector('Method',AlgType,...
                    'NumTrainingCells',Ntraining(1),'NumGuardCells',Nguard(1),...
                    'ProbabilityFalseAlarm',Pfa_goal,'ThresholdOutputPort',true,...
                    'ThresholdFactor','custom',...
                  'CustomThresholdFactor',THFac);
            end
            CFAR_Detector = detector;
            CFAR_Detector1= detector1;
            CFAR_Detector2= detector2;
            save("CFARDet.mat","CFAR_Detector","CFAR_Detector1","CFAR_Detector2","Ntraining","Nguard");
        end

        % Button pushed function: showrangedopplerresponseButton
        function showrangedopplerresponseButtonPushed(app, event)
            fft_size = str2num(app.fftsizeEditField.Value);
            rain_speed = str2num(app.rainspeedmsEditField.Value);
            density    = str2num(app.densityEditField.Value);
            rngBinsNum = str2num(app.rangebinsnumEditField.Value);
            rain_steering = str2num(app.rainsteeringdegreeEditField.Value);
            Noise_level = str2num(app.noiseleveldBEditField.Value);
            distortion = str2num(app.distortionEditField.Value);
            app.waveLength = app.prop_speed/app.fc;
            speed_axis = dop2speed([-app.prf/2 + app.prf/fft_size:app.prf/fft_size:app.prf/2],app.waveLength) ;
            ang = [180:-1:-180];
            fast_time_grid = unigrid(0,1/app.fs,1/max(app.prf),'[)');
            range_gates = app.prop_speed*fast_time_grid/2;
            tic;
            for j = 1:length(ang)
                signal = rain_clutter(density,fft_size,rngBinsNum,rain_speed,...
                    distortion,ang(j),rain_steering,speed_axis,Noise_level);
                kk_time_p = [];
                for i = 1:fft_size
                    kk_time_p = [kk_time_p signal(:,i)];
                end
                kk2 = fftshift(fft(kk_time_p,fft_size,2),2);
                figure(1);
                pcolor(speed_axis,range_gates,abs(kk2));shading interp;
                   
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ClutterConfigrationLabel = uilabel(app.UIFigure);
            app.ClutterConfigrationLabel.Text = 'Clutter Configration';
            app.cluttergammamodelDropDownLabel = uilabel(app.UIFigure);
            app.cluttergammamodelDropDownLabel.Text = 'clutter gamma model';
            app.cluttergammamodelDropDown = uidropdown(app.UIFigure);
            app.cluttergammamodelDropDown.Items = {'flatLand', 'woods', 'seaState', 'Rain'};
            app.cluttergammamodelDropDown.Value = 'seaState';
            app.EarthModelDropDownLabel = uilabel(app.UIFigure);
            app.EarthModelDropDownLabel.Text = 'EarthModel';
            app.EarthModelDropDown = uidropdown(app.UIFigure);
            app.EarthModelDropDown.Items = {'Flat', 'Curved'};
            app.EarthModelDropDown.Value = 'Flat';
            app.AzimuthCoveragedegreeEditFieldLabel = uilabel(app.UIFigure);
            app.AzimuthCoveragedegreeEditFieldLabel.Text = 'Azimuth Coverage (degree)';
            app.AzimuthCoveragedegreeEditField = uieditfield(app.UIFigure, 'text');
            app.AzimuthCoveragedegreeEditField.Value = '360';
            app.AzemuthBeamwidthdegreeEditFieldLabel = uilabel(app.UIFigure);
            app.AzemuthBeamwidthdegreeEditFieldLabel.Text = 'Azemuth Beamwidth   (degree)';
            app.AzemuthBeamwidthdegreeEditField = uieditfield(app.UIFigure, 'text');
            app.AzemuthBeamwidthdegreeEditField.Value = '2';
            app.RadarHeightmEditFieldLabel = uilabel(app.UIFigure);
            app.RadarHeightmEditFieldLabel.Text = 'Radar Height    (m)';
            app.RadarHeightmEditField = uieditfield(app.UIFigure, 'text');
            app.RadarHeightmEditField.Value = '40';
            app.createButton = uibutton(app.UIFigure, 'push');
            app.createButton.ButtonPushedFcn = createCallbackFcn(app, @createButtonPushed, true);
            app.createButton.Text = 'create';
            app.plotdopplerazimuthresponseButton = uibutton(app.UIFigure, 'push');
            app.plotdopplerazimuthresponseButton.ButtonPushedFcn = createCallbackFcn(app, @plotdopplerazimuthresponseButtonPushed, true);
            app.plotdopplerazimuthresponseButton.Text = {'plot        doppler'; ' azimuth response'};
            app.CFARConfigrationLabel = uilabel(app.UIFigure);
            app.CFARConfigrationLabel.Text = 'CFAR Configration';
            app.numberoftrainingcellsEditFieldLabel = uilabel(app.UIFigure);
            app.numberoftrainingcellsEditFieldLabel.Text = 'number of training cells';
            app.numberoftrainingcellsEditField = uieditfield(app.UIFigure, 'text');
            app.numberoftrainingcellsEditField.Value = '[50 122]';
            app.numberofguardcellsEditFieldLabel = uilabel(app.UIFigure);
            app.numberofguardcellsEditFieldLabel.Text = 'number of guard cells';
            app.numberofguardcellsEditField = uieditfield(app.UIFigure, 'text');
            app.numberofguardcellsEditField.Value = '[4 4]';
            app.ThresholdFactorLabel = uilabel(app.UIFigure);
            app.ThresholdFactorLabel.Text = 'Threshold Factor';
            app.ThresholdFactorSlider = uislider(app.UIFigure);
            app.ThresholdFactorSlider.Limits = [0 50];
            app.ThresholdFactorSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.falsealarmprobabilityEditFieldLabel = uilabel(app.UIFigure);
            app.falsealarmprobabilityEditFieldLabel.Text = 'false alarm probability';
            app.falsealarmprobabilityEditField = uieditfield(app.UIFigure, 'text');
            app.falsealarmprobabilityEditField.Value = '1e-6';
            app.createButton_2 = uibutton(app.UIFigure, 'push');
            app.createButton_2.ButtonPushedFcn = createCallbackFcn(app, @createButton_2Pushed, true);
            app.createButton_2.Text = 'create';
            app.RadarspeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.RadarspeedmsEditFieldLabel.Text = 'Radar speed  (m/s)';
            app.RadarspeedmsEditField = uieditfield(app.UIFigure, 'text');
            app.RadarspeedmsEditField.Value = '0';
            app.loadparametersButton = uibutton(app.UIFigure, 'push');
            app.loadparametersButton.ButtonPushedFcn = createCallbackFcn(app, @loadparametersButtonPushed, true);
            app.loadparametersButton.Text = {'load '; 'parameters'};
            app.CFARTypeDropDownLabel = uilabel(app.UIFigure);
            app.CFARTypeDropDownLabel.Text = 'CFAR Type';
            app.CFARTypeDropDown = uidropdown(app.UIFigure);
            app.CFARTypeDropDown.Items = {'1D', '2D'};
            app.CFARTypeDropDown.Value = '1D';
            app.depressionangledegreeEditFieldLabel = uilabel(app.UIFigure);
            app.depressionangledegreeEditFieldLabel.Text = 'depression angle  (degree)';
            app.depressionangledegreeEditField = uieditfield(app.UIFigure, 'text');
            app.depressionangledegreeEditField.Value = '0';
            app.CFARAlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.CFARAlgorithmDropDownLabel.Text = 'CFAR Algorithm';
            app.CFARAlgorithmDropDown = uidropdown(app.UIFigure);
            app.CFARAlgorithmDropDown.Items = {'CA', 'SOCA', 'GOCA', 'OS'};
            app.CFARAlgorithmDropDown.Value = 'GOCA';
            app.GroundClutterLabel = uilabel(app.UIFigure);
            app.GroundClutterLabel.Text = 'Ground Clutter';
            app.RainClutterLabel = uilabel(app.UIFigure);
            app.RainClutterLabel.Text = 'Rain Clutter';
            app.rainspeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.rainspeedmsEditFieldLabel.Text = 'rain speed  (m/s)';
            app.rainspeedmsEditField = uieditfield(app.UIFigure, 'text');
            app.rainspeedmsEditField.Value = '10';
            app.rainsteeringdegreeEditFieldLabel = uilabel(app.UIFigure);
            app.rainsteeringdegreeEditFieldLabel.Text = 'rain steering    (degree)';
            app.rainsteeringdegreeEditField = uieditfield(app.UIFigure, 'text');
            app.rainsteeringdegreeEditField.Value = '30';
            app.densityEditFieldLabel = uilabel(app.UIFigure);
            app.densityEditFieldLabel.Text = 'density';
            app.densityEditField = uieditfield(app.UIFigure, 'text');
            app.densityEditField.Value = '8e-4';
            app.noiseleveldBEditFieldLabel = uilabel(app.UIFigure);
            app.noiseleveldBEditFieldLabel.Text = 'noise level  (dB)';
            app.noiseleveldBEditField = uieditfield(app.UIFigure, 'text');
            app.noiseleveldBEditField.Value = '10';
            app.showrangedopplerresponseButton = uibutton(app.UIFigure, 'push');
            app.showrangedopplerresponseButton.ButtonPushedFcn = createCallbackFcn(app, @showrangedopplerresponseButtonPushed, true);
            app.showrangedopplerresponseButton.Text = {'show range    doppler'; 'response'};
            app.fftsizeEditFieldLabel = uilabel(app.UIFigure);
            app.fftsizeEditFieldLabel.Text = 'fft size';
            app.fftsizeEditField = uieditfield(app.UIFigure, 'text');
            app.fftsizeEditField.Value = '128';
            app.rangebinsnumEditFieldLabel = uilabel(app.UIFigure);
            app.rangebinsnumEditFieldLabel.Text = 'range bins num';
            app.rangebinsnumEditField = uieditfield(app.UIFigure, 'text');
            app.rangebinsnumEditField.Value = '200';
            app.distortionEditFieldLabel = uilabel(app.UIFigure);
            app.distortionEditFieldLabel.Text = 'distortion';
            app.distortionEditField = uieditfield(app.UIFigure, 'text');
            app.distortionEditField.Value = '2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clutterAndCFAR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end